classdef ea_cleartune < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        OptimizeStimulationvolumecalculationsMenu  matlab.ui.container.Menu
        PatientSelectionButtonGroup     matlab.ui.container.ButtonGroup
        UsepatientsfrommodelfilesleavenothingoutButton  matlab.ui.control.RadioButton
        BusyLamp                        matlab.ui.control.Lamp
        BusyLampLabel                   matlab.ui.control.Label
        ChoosePatientFoldersButton      matlab.ui.control.Button
        UsepatientsfrommodelfilesleaveoneoutButton  matlab.ui.control.RadioButton
        SelectexternalPatientsButton    matlab.ui.control.RadioButton
        TabGroup                        matlab.ui.container.TabGroup
        SuggestStimulationParametersTab  matlab.ui.container.Tab
        StepSizeEditField               matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        MaximumAmplitudeEditField       matlab.ui.control.NumericEditField
        MaximumAmplitudeEditFieldLabel  matlab.ui.control.Label
        MinimumAmplitudeEditField       matlab.ui.control.NumericEditField
        MinimumAmplitudeEditFieldLabel  matlab.ui.control.Label
        AutoRefreshCheckBox             matlab.ui.control.CheckBox
        VisualizeResultsCheckBox        matlab.ui.control.CheckBox
        SuggestStimulationParametersButton  matlab.ui.control.Button
        ConstCurrDropdown               matlab.ui.control.DropDown
        PredictOutcomesforExistingParametersTab  matlab.ui.container.Tab
        StimulationSettingDropDown      matlab.ui.control.DropDown
        StimulationSettingDropDownLabel  matlab.ui.control.Label
        PredictOutcomesButton           matlab.ui.control.Button
        SymptomWeightsPanel             matlab.ui.container.Panel
        ChooseSweetspotMappingModelButton  matlab.ui.control.Button
        ChooseNetworkMappingModelButton  matlab.ui.control.Button
        ChooseFiberfilteringModelButton  matlab.ui.control.Button
    end


    properties (Access = private)
        fibfiltmodelpath % which fibfiltmodel to use
        netmappingmodelpath % which netmapping model to use (not implemented)
        sweetspotmodelpath % which sweetspotmodel to use (not implemented)
        patientlist={} % cell with patient folders loaded
        resultfig % resultfigure
        visualizeresults=1 % show results
        tractset % tractset object loaded from fibfiltmodelpath or received from input config
        protocol % efields & base vta protocol stored to not recalc each time
        elrender % handle to current electrode if present
        ellabel % label of current electrode
        percimp % estimates of improvements under present suggestions
        sliderimprovement = {} % handles for slider improvements
        slider = {}
        slider_name = {} % sliders
        slider_text_box = {}% text boxes
        slider_lab_name = {}% slider label name
        basepredlbl % base prediction on label
        basepreddpd % base prediction dropdown
    end

    methods (Access = private)

        function results = enablerules(app)
            if isempty(app.patientlist)
                app.ChoosePatientFoldersButton.Text = 'Choose Patient Folder(s)...';
            elseif length(app.patientlist)==1
                app.ChoosePatientFoldersButton.Text=app.patientlist{1};
                app.VisualizeResultsCheckBox.Enable='on';
                app.visualizeresults=app.VisualizeResultsCheckBox.Value;
            else
                app.ChoosePatientFoldersButton.Text=['Multiple (',num2str(length(app.patientlist)),')'];
                app.VisualizeResultsCheckBox.Enable='off';
                app.visualizeresults=0;
            end
            
            app.refreshweightsliders;


            if ~isempty(app.patientlist)

                for pt=1:length(app.patientlist)
                    direc=[app.patientlist{pt},filesep,'stimulations',filesep,ea_getspace];
                    stims = ea_regexpdir(direc,'.*',0,'d');
                    %stims=ea_dir2cell(dir([direc,'stimulations',filesep,ea_getspace]));
                    if ~exist('remstims','var')
                        remstims=stims;
                    else
                        todel=[];
                        for d=1:length(remstims)
                            if ~ismember(remstims{d},stims)
                                todel(end+1)=d;
                            end
                        end
                        remstims(todel)=[];
                    end
                end
                app.StimulationSettingDropDown.Items=remstims;
            end

            switch app.PatientSelectionButtonGroup.SelectedObject.Text
                case 'Select external Patients:'
                    app.ChoosePatientFoldersButton.Enable='on';
                otherwise
                    app.ChoosePatientFoldersButton.Enable='off';
            end
        end

        function results = base_pred_fcn(app,i)
            
            saved_labels = app.tractset.subscore.labels{i};
            saved_vars = app.tractset.subscore.vars{i};
            if strcmp(app.basepreddpd{i}.Value,'None')
                app.slider_text_box{i}.Enable = 'on';
                app.slider{i}.Enable = 'on';
                app.tractset.subscore.weightvars{i,1} = '';
                app.tractset.subscore.weightvars{i,1} = [];
                app.tractset.subscore.weights(i)=app.slider{i}.Value/100;
            else
                app.slider_text_box{i}.Enable = 'off';
                app.slider{i}.Enable = 'off';
                index_of_new_subscore = cellfun(@(x)isequal(x,app.basepreddpd{i}.Value),app.tractset.M.clinical.labels);
                app.tractset.subscore.weightvars{i,1} = app.tractset.M.clinical.vars{index_of_new_subscore};
                
                app.tractset.subscore.weightvarlabels{i,1} = app.basepreddpd{i}.Value;
                app.tractset.subscore.weights(i)=nan;
            end

        end

        function results = SliderChange_text(app,event,i)
            text_value = app.slider_text_box{i}.Value;
            remainingamount=1-(text_value/100);
            otherentries=1:length(app.slider);
            otherentries(i)=[];
            weights=app.tractset.subscore.weights;
            weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
            weights(i)=text_value/100;

            for slide_cnt=1:length(app.slider)
                if ~isnan(weights(slide_cnt))
                    app.slider_text_box{slide_cnt}.Value=weights(slide_cnt)*100;
                    app.tractset.subscore.weights(slide_cnt) = [weights(slide_cnt)];
                    app.slider{slide_cnt}.Value = weights(slide_cnt)*100;
                end
            end
            app.draw;
        end

        function results = SliderChange(app,event,i)
            %change value using sliders
            remainingamount=1-(app.slider{i}.Value/100);
            otherentries=1:length(app.slider);
            otherentries(i)=[];
            weights=app.tractset.subscore.weights;
            weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
            weights(i)=app.slider{i}.Value/100;

            for slide_cnt=1:length(app.slider)
                if ~isnan(weights(slide_cnt))
                    app.slider{slide_cnt}.Value=weights(slide_cnt)*100;
                    app.tractset.subscore.weights(slide_cnt) = [weights(slide_cnt)];
                    app.slider_text_box{slide_cnt}.Value = weights(slide_cnt)*100;
                end
            end

            app.draw;
        end

        function results = SliderChanging(app,event,i)

            remainingamount=1-(event.Value/100);
            otherentries=1:length(app.slider);
            otherentries(i)=[];
            weights=app.tractset.subscore.weights;
            weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
            weights(i)=event.Value/100;

            for slide_cnt=1:length(app.slider)
                if ~isnan(weights(slide_cnt))
                    app.slider{slide_cnt}.Value=weights(slide_cnt)*100;
                    app.tractset.subscore.weights(slide_cnt) = [weights(slide_cnt)];
                    app.slider_text_box{slide_cnt}.Value = weights(slide_cnt)*100;
                end
            end
        end

        function results = draw(app,force,event)
            if ~exist('force','var')
                force=0;
            end
            if force || app.AutoRefreshCheckBox.Value
                app.isbusy(1);


                switch app.PatientSelectionButtonGroup.SelectedObject.Text
                    case 'Select external Patients:'
                        if isempty(app.patientlist)
                            ea_error('Please select one or more patients first.')
                        end
                        patlist=app.patientlist;
                        trainings=repmat({app.tractset.patientselection},length(patlist),1);
                    case 'Use patients from model files (leave-one-out)'
                        patlist=app.tractset.M.patient.list(app.tractset.patientselection);
                        for i=1:length(app.tractset.patientselection)
                            trainings{i,:} = ones(1,length(app.tractset.patientselection));
                        end
                        
                       % trainings = patlist;
                       %  for t=1:length(patlist)
                       %      trainings{t}=app.tractset.patientselection(t);
                       %       trainings{t}(trainings{t}==app.tractset.patientselection(t))=[];
                       %  end
                    case 'Use patients from model files (leave-nothing-out)'
                        patlist=app.tractset.M.patient.list(app.tractset.patientselection);
                        trainings=repmat({app.tractset.patientselection},length(patlist),1);
                        %trainings=repmat({app.tractset.patientselection},length(app.patientlist),1);
                end


                for pt=1:length(patlist)
                   

                    inputs={patlist(pt),app.MinimumAmplitudeEditField.Value,app.MaximumAmplitudeEditField.Value,app.StepSizeEditField.Value,app.ConstCurrDropdown.Value};
                    switch event
                        case 'suggest'

                            recalc=1;
                            if ~isempty(app.protocol) % base vtas have been calculated before. If they were done using the same inputs, no need to recalc
                                if length(app.protocol)>=pt
                                    if isequal(app.protocol{pt}.inputs,inputs)
                                        recalc=0;
                                    end
                                end
                            end
                            if recalc
                                [Efields,allS]=ea_generate_base_vats(inputs{:});
                                app.protocol{pt}.inputs=inputs;
                                app.protocol{pt}.Efields=Efields;
                                app.protocol{pt}.allS=allS;
                                % tried storing the protocol but it's >1 GB, faster to
                                % recalc each time since its quick.
                                %ea_mkdir(fullfile(app.patientlist{pt},'cleartune'));
                                %d=app.protocol{pt};
                                %save(fullfile(app.patientlist{pt},'cleartune','protocol.mat'),'-struct','d');
                            end
                        case 'predict'
                            Vvate=ea_load_nii(fullfile(patlist{pt},'stimulations',ea_getspace,app.StimulationSettingDropDown.Value,'vat_efield_right.nii')); % rh
                            app.protocol{pt}.Efields(1,1)=Vvate;
                            Vvate=ea_load_nii(fullfile(patlist{pt},'stimulations',ea_getspace,app.StimulationSettingDropDown.Value,'vat_efield_left.nii')); % lh
                            app.protocol{pt}.Efields(1,2)=Vvate;
                    end
                    if isempty(app.tractset)
                    
                        
                        d=load(app.fibfiltmodelpath,'-mat');
                        app.tractset=d.tractset;
                        clear d
                    end

                    tractsetclone=ea_disctract;
                    app.tractset.copyobj(tractsetclone);
                   % tractsetclone.ID = 'retest3';

                    tractsetclone.calculate_cleartune(app.protocol{pt}.Efields);
                    app.tractset.cleartuneresults=tractsetclone.cleartuneresults; % this is fine to copy to the main object for visualization later.


                    %% >>>>>>>>>> BEGIN INJECTION:

                    %% temporarily append cleartune results to results to feed into
                    % crossvalidation:

                    % this is crucial to be cleaned up again and cannot go
                    % wrong, i.e. anything in between injection and cleanup
                    % needs to be in a try/catch statement.

                    if ~isempty(tractsetclone.cleartuneinjected)
                        if ~strcmp(tractsetclone.cleartuneinjected.status,'clean')
                            ea_error('Fiber Filtering File is corrupted. Aborting.');
                        end
                    end

                    tractsetclone.cleartuneinjected.status='injected';
                    origlen=size(tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{1},2)/2;
                    numEfields=size(app.protocol{pt}.Efields,1);
                    origix=1:origlen;
                    efieldix=1:numEfields;
                    origlen=length(origix);
                    efieldlen=length(efieldix);
                    totallen=origlen+efieldlen;
                    tractsetclone.cleartuneinjected.origlen=origlen; % protocol
                    tractsetclone.cleartuneinjected.efieldlen=efieldlen;
                    tractsetclone.cleartuneinjected.totallen=totallen;

                    tmpfibvals=tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval;

                    for side=1:2
                        tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}=...
                            [tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}(:,origix),...
                            tractsetclone.cleartuneresults.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}(:,efieldix),...
                            tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}(:,origlen+origix),...
                            tractsetclone.cleartuneresults.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}(:,efieldix)]; % no need to flip here but keep copy for flipped entries
                    end

                    tractsetclone.allpatients=[tractsetclone.allpatients;repmat({''},size(app.protocol{pt}.Efields,1),1)];

                    tractsetclone.responsevar=[tractsetclone.responsevar;nan(size(app.protocol{pt}.Efields,1),size(tractsetclone.responsevar,2))];
                    reduceresponsevar=0;
                    switch event
                        case 'suggest'
                            if size(tractsetclone.responsevar,2) == 1 % global scores
                                tractsetclone.responsevar=[tractsetclone.responsevar,tractsetclone.responsevar];    % we will need maximal stim settings for each site separately, so we will pretend responsevar is bihemispheric
                                reduceresponsevar=1;
                            end
                    end
                    switch tractsetclone.multitractmode
                        case 'Single Tract Analysis'
                        otherwise
                            
                            for wv=1:length(tractsetclone.subscore.weights)
                                if isnan(tractsetclone.subscore.weights(wv))
                                    tractsetclone.subscore.weightvars{wv}=[app.tractset.subscore.weightvars{wv};ones(length(app.protocol{pt}.Efields),1)]; % need to add in weights here.
                                end
                            end
                            for wv=1:length(tractsetclone.subscore.vars)
                                tractsetclone.subscore.vars{wv}=[tractsetclone.subscore.vars{wv};ones(length(app.protocol{pt}.Efields),1)];
                            end
                            
%                             for wv=1:length(tractsetclone.subscore.weightvars)
%                                 if isnan(tractsetclone.subscore.weights(wv))
%                                     tractsetclone.subscore.weightvars{wv}=[tractsetclone.subscore.weightvars{wv};ones(length(app.protocol{pt}.Efields),1)]; % need to add in weights here.
%                                 end
%                             end
%                             for wv=1:length(tractsetclone.subscore.vars)
%                                 tractsetclone.subscore.vars{wv}=[tractsetclone.subscore.vars{wv};ones(length(app.protocol{pt}.Efields),1)];
%                             end
                    end

                    %% <<<<<<<<<<<   END INJECTION

                    %% run custom crossvalidation to determine optimal stimulation setting:
                    try % this has to be in a try/catch because we still have to cleanup the fibfilt again if something would go wrong (next section).
                        cvp.NumTestSets = 1;
                        % training and test indices from the items list
                        test = origlen+1:origlen+size(app.protocol{pt}.Efields,1);
                        % Patient selected based on the training and test indices
                        tot_len = origlen+length(test);
                        
                        if strcmp(app.PatientSelectionButtonGroup.SelectedObject.Text,'Use patients from model files (leave-one-out)')
                            new_trainings = [trainings{pt},zeros(1,length(test))];
                            tractsetclone.customselection = linspace(1,tot_len,tot_len);
                            cvp.training{1} = logical(new_trainings);
                            cvp.test{1} = ~cvp.training{1};
                        elseif strcmp(app.PatientSelectionButtonGroup.SelectedObject.Text,'Use patients from model files (leave-nothing-out)')
                            tractsetclone.customselection = unique([trainings{pt}, test]);
                            cvp.training{1} = ismember(tractsetclone.customselection, trainings{pt});
                            cvp.test{1} = ismember(tractsetclone.customselection, test);

                        else
                            tractsetclone.customselection = unique([trainings{pt}, test]);
                            cvp.training{1} = ismember(tractsetclone.customselection, trainings{pt});
                            cvp.test{1} = ismember(tractsetclone.customselection, test);

                        end
                        
                        % Construct cvp struct
                        
                        [I, Ihat, actualimprovs]=tractsetclone.crossval(cvp); % actualimprovs is for each side separately.


                        switch event
                            case 'suggest'
                                Ihat=[Ihat(1:length(Ihat)/2),Ihat(length(Ihat)/2+1:end)]; % Ihat is exported as a column vector for both sides. Reformat to Nx2.
                                if sum(isnan(Ihat(:)))/length(Ihat(:))>0.3
                                    ea_warning(['Many (',num2str(sum(isnan(Ihat(:)))*100/length(Ihat(:))),' percent) stimulation settings were not covered well by the model. Stimulation suggestions may not be meaningful. Please adjust model parameters in ',...
                                        app.fibfiltmodelpath,'.']);
                                end
                                for side=1:2
                                    [is,ix(side)]=ea_nanmax(Ihat(:,side));

                                    S(side)=app.protocol{pt}.allS(ix(side)); % optimal setting for this electrode
                                end

                                % this is broken: Fix return of
                                % actualimprovs to revive:

                                for actimp=1:size(actualimprovs,1)
                                    for side=1:2
                                        app.percimp(actimp,side)=actualimprovs{actimp,side}(ix(side));
                                    end
                                    app.sliderimprovement{actimp}.Text=[sprintf('%.1f',app.percimp(actimp,1)*100),'% / ',sprintf('%.1f',app.percimp(actimp,2)*100),'%'];
                                end


                                S=ea_conflateS_lr(S); % merge entries from right (S(1)) and left (S(2)) stim params to one. => S
                            case 'predict'
                                disp(['Prediction for ',patlist{pt},': ',num2str(Ihat)]);
                        end

                    catch
                        ea_warning('Cleartune did not find a solution.')
                    end


                    %% >>>>>>>   BEGIN CLEANUP

                    %% cleanup again: not as important anymore since we are working on a clone of the object.

                    % remove appends from responsevar and weightmatrices:
                    tractsetclone.responsevar=tractsetclone.responsevar(1:origlen,:);
                    tractsetclone.allpatients=tractsetclone.allpatients(1:origlen,:);

                    if reduceresponsevar
                        tractsetclone.responsevar=tractsetclone.responsevar(:,1);
                    end
                    switch tractsetclone.multitractmode
                        case 'Single Tract Analysis'
                        otherwise
                            for wv=1:length(tractsetclone.subscore.weights)
                                if isnan(tractsetclone.subscore.weights(wv))
                                    tractsetclone.subscore.weightvars{wv}=tractsetclone.subscore.weightvars{wv}(1:origlen);
                                end
                            end
                            for wv=1:length(tractsetclone.subscore.vars)
                                tractsetclone.subscore.vars{wv}=tractsetclone.subscore.vars{wv}(1:origlen);
                            end
                    end
                    % remove cleartune results again:
                    for side=1:2
                        tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}=...
                            [tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}(:,origix),...
                            tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval{side}(:,totallen+origix),...
                            ];
                    end

                    % check if all went correctly:
                    if isequaln(tractsetclone.results.(ea_conn2connid(tractsetclone.connectome)).(ea_method2methodid(tractsetclone)).fibsval,tmpfibvals)
                        tractsetclone.cleartuneinjected.status='clean';
                    else
                        ea_error('Something went wrong.');
                    end

                    %% END CLEANUP  <<<<<<<<

                    switch event
                        case 'suggest'
                            %% export optimal vta / stim setting
                            options=ea_getptopts(patlist{pt});
                            options=ea_defaultoptions(options);
                            options.native=0;
                            stimfolderout=fullfile(patlist{pt},'stimulations',ea_nt(options),['cleartune_',tractsetclone.ID]);
                            ea_mkdir(stimfolderout);
                            [options.root,options.patientname]=fileparts(patlist{pt});
                            options.root=[options.root,filesep];
                            save(fullfile(stimfolderout,[options.patientname,'_desc-stimparameters.mat']),'S');

                            % output VTA:
                            ea_genvat=@ea_genvat_fastfield;


                            options.prefs=ea_prefs;
                            % maybe double check this is the BIDS conform
                            % way of doing things:
                            [coords_mm,trajectory,markers,elmodel,manually_corrected,coords_acpc]=ea_load_reconstruction(patlist{pt});
                            elstruct.coords_mm=coords_mm;
                            elstruct.coords_acpc=coords_acpc;
                            elstruct.trajectory=trajectory;
                            elstruct.name = options.patientname;
                            elstruct.markers=markers;
                            options.elmodel=elmodel;
                            options=ea_resolve_elspec(options);

                            options.leadprod='dbs';
                            options=ea_detsides(options);
                            if isempty(app.resultfig) % cleartune was likely opened as standalone.
                                if app.visualizeresults
                                    app.resultfig=ea_mnifigure;
                                    % since was empty need to assume fibfilt and electrodes are not shown
                                    % either:
                                    [app.elrender,app.ellabel]=ea_showelectrodes(options,app.resultfig);
                                    ea_addobj([], [], app.resultfig, {app.fibfiltmodelpath}, options);
                                else % create a fake resultfig hidden figure
                                    app.resultfig=figure('Visible',"off");
                                end
                            else % cleartune was likely opened from within the fiber filtering explorer GUI.
                               % try delete(app.elrender); end
                                % maybe double check if this is the correct
                                % way to visualize electrodes:
                                if length(patlist) == 1
                                    [el_render, el_label, elSide] = ea_renderelstruct(options,app.resultfig,elstruct);
                                else
                                    if pt == 1
                                        msgbox("Since there are multiple patients, showing the electrode reconstruction of the first patient");
                                        [el_render, el_label, elSide] = ea_renderelstruct(options,app.resultfig,elstruct);
                                    end
                                end
                                %don't give pt as an argument and it will
                                %always update to the next one
                                %[el_render, el_label, elSide] = ea_renderelstruct(options,app.resultfig,elstruct,pt);
                            end
                            setappdata(app.resultfig,'elstruct',elstruct);
                            setappdata(app.resultfig,'options',options);
                            setappdata(app.resultfig,'elspec',options.elspec);
                            setappdata(app.resultfig,'resultfig',app.resultfig);

                            stimparams = struct();

                            for side=1:2
                                [vtafv,vtavolume] = feval(ea_genvat,coords_mm,S,side,options,['cleartune_',tractsetclone.ID],app.resultfig);
                                stimparams(1,side).VAT.VAT = vtafv;
                                stimparams(1,side).volume = vtavolume;
                            end


                            if app.visualizeresults
                                %% visualize vta

                                PL=getappdata(app.resultfig,'PL');
                                for group=1:length(PL)
                                    ea_deletePL(PL(group));
                                end
                                clear PL
                                options.expstatvat.do=0;
                                options.sides=1:2;
                                options.writeoutstats=0;
                                setappdata(app.resultfig,'stimparams',stimparams(1,:));
                                setappdata(app.resultfig,'curS',S(1));

                                if ~exist('hmchanged','var')
                                    hmchanged=1;
                                end
                                ea_calc_vatstats(app.resultfig,options,hmchanged);

                                %% activate fibs by optimal stim
                                app.tractset.activateby={['cleartune',num2str(ix(1)),'_1'],['cleartune',num2str(ix(2)),'_2']};
                                app.tractset.activate_tractset

                            end


                        case 'predict'

                    end

                    %switch event
                    %    case 'suggest'
                            %if length(patlist)>1

                              %  msgbox({'Optimal stimulation settings generated!',...
                              %      '',...
                              %      'Since multiple patients have been selected, results cannot be visualized, directly.',...
                              %      'Optimal stimulation settings as calculated by the present model & setting',...
                              %      ['have been exported to each selected patient folder under the stimulation name cleartune_',app.tractset.ID], ...
                              %      'and can be visualized using Lead-DBS.'})

                     %       end
                    %end
                    app.enablerules;
                    app.isbusy(0);
                end
            else
               app.isbusy(2);
            end
        end

        function results = isbusy(app,business,msg)
            if business == 1 % red
                app.BusyLamp.Color = [1.00,0.41,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Busy';
                end
            elseif business == 0 % green
                app.BusyLamp.Color = [0.41,1.00,0.16];
                app.BusyLampLabel.Text = '';
            elseif business == 2 % yellow
                app.BusyLamp.Color = [1.00,0.8,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Not Refreshed';
                end
            end
            drawnow;
        end
        function  deletePreExistingComp(app)

            for i=1:length(app.tractset.subscore.vars)
               if ~isempty(app.slider)
                  if strcmp(app.slider{i}.Enable,'on')
                       delete(app.slider{i})
                  end
               end
               if ~isempty(app.slider_lab_name)
                   if ~strcmp(app.slider_lab_name{i}.Text,'')
                       app.slider_lab_name{i}.Text = '';
                   end
               end
               if ~isempty(app.slider_text_box)
                   if app.slider_text_box{i}.Value ~= 0
                       delete(app.slider_text_box{i})
                   end
               end
               if ~isempty(app.sliderimprovement)
                   if ~strcmp(app.sliderimprovement{i}.Text,'')
                       app.sliderimprovement{i}.Text = '';
                   end
               end
            end


        end
        function results = refreshweightsliders(app)
            if ~isempty(app.tractset)
                switch app.tractset.multitractmode
                    case 'Single Tract Analysis Button'
                    otherwise
                       %needs to be here since the components are not
                       %defined in properties, hence they won't be
                       %accessible outside the scope of this function
                      
                       deletePreExistingComp(app);

                        % weight sliders:
                        for i=1:length(app.tractset.subscore.vars)
                            app.slider_name{i} = app.tractset.subscore.labels{i};
                        end

                        j = 390; %start position of the label
                        k = 380; %start position of the slider
                        l = 360;
                        m = 362;


                        for i=1:length(app.slider_name)
                            app.slider_lab_name{i} = uilabel(app.SymptomWeightsPanel,'Text',app.slider_name{i},'Position',[20,j,500,22],'FontWeight','bold');
                            app.slider{i} = uislider(app.SymptomWeightsPanel,'Limits',[0 100]);
                            app.slider{i}.Position(1) = 250;
                            app.slider{i}.Position(2) = k;
                            app.slider{i}.Position(3) = 200;
                            app.slider_text_box{i} = uieditfield(app.SymptomWeightsPanel,'numeric','Position',[500,l,50,22]);
                            app.sliderimprovement{i} = uilabel(app.SymptomWeightsPanel,'Text','','Position',[500,j,500,22]);

                            % ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{i,1}),app.tractset.M.clinical.labels);
                            app.basepredlbl{i} = uilabel(app.SymptomWeightsPanel,'Text','Weight Using..','Position',[20,m,80,25]);
                            app.basepreddpd{i} = uidropdown(app.SymptomWeightsPanel,'Items',horzcat('None',app.tractset.M.clinical.labels),'Value','None','Position',[100,m,80,25]);
                            j = j-70;
                            k = k-70;
                            l = l-70;
                            m = m-70;

                            % update values already stored
                            if length(app.tractset.subscore.weights)>=i
                                if ~isnan(app.tractset.subscore.weights(i))
                                    app.slider{i}.Value=app.tractset.subscore.weights(i)*100;
                                    app.slider_text_box{i}.Value=app.tractset.subscore.weights(i)*100;
                                    app.slider{i}.Enable='on';
                                    app.slider_text_box{i}.Enable='on';
                                else
                                    app.slider{i}.Enable='off';
                                    app.slider_text_box{i}.Enable='off';
                                    app.basepreddpd{i}.Value=app.tractset.subscore.weightvarlabels{i};
                                end
                            else
                                app.slider{i}.Enable='off';
                                app.slider_text_box{i}.Enable='off';
                                app.basepreddpd{i}.Value='None';
                            end

                        end

                        for i=1:length(app.slider)
                            app.slider{i}.ValueChangedFcn = @(slider,event) SliderChange(app,event,i);
                            app.slider{i}.ValueChangingFcn = @(slider,event) SliderChanging(app,event,i);
                            app.slider_text_box{i}.ValueChangedFcn = @(slider_text_box,event) SliderChange_text(app,event,i);
                            app.basepreddpd{i}.ValueChangedFcn = @(basepreddpd,event) base_pred_fcn(app,i);
%                           
                        end
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, config)
            app.UIFigure.Name='Lead-DBS Cleartune';


            if exist('config','var')

                if isfield(config,'fibfiltmodelpath')

                    app.ChooseFiberfilteringModelButton.Text=config.fibfiltmodelpath;
                    app.fibfiltmodelpath=config.fibfiltmodelpath;

                end
                if isfield(config,'tractset')
                    app.tractset=config.tractset;
                end
                if isfield(config,'resultfig')
                    app.resultfig=config.resultfig;
                end
                if isfield(config,'patientlist')
                    if ~iscell(config.patientlist)
                        app.patientlist{1}=config.patientlist;
                    else
                        app.patientlist=config.patientlist;
                    end

                end

            end

            app.enablerules;


            app.isbusy(0);

        end

        % Button pushed function: SuggestStimulationParametersButton
        function SuggestStimulationParametersButtonPushed(app, event)
            app.draw(1,'suggest');
        end

        % Button pushed function: ChoosePatientFoldersButton
        function ChoosePatientFoldersButtonPushed(app, event)
            app.patientlist=ea_getpatients;
            app.enablerules;
            app.draw;
        end

        % Button pushed function: ChooseFiberfilteringModelButton
        function ChooseFiberfilteringModelButtonPushed(app, event)
            [ff,pth]=uigetfile('*.fibfilt');
            app.fibfiltmodelpath=fullfile(pth,ff);
            app.ChooseFiberfilteringModelButton.Text=app.fibfiltmodelpath;
            app.isbusy(1,'Loading FibFilt File...');
            d=load(app.fibfiltmodelpath,'-mat');
            app.tractset=d.tractset;
            clear d
            app.isbusy(0);
            app.enablerules;
            app.draw;
        end

        % Value changed function: VisualizeResultsCheckBox
        function VisualizeResultsCheckBoxValueChanged(app, event)
            value = app.VisualizeResultsCheckBox.Value;
            app.visualizeresults=value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.tractset.activateby={};
                app.tractset.activate_tractset;
            end
            for el=1:length(app.elrender)
                try delete(app.elrender(el).elpatch); end
            end
            try
                PL=getappdata(app.resultfig,'PL');
                for group=1:length(PL)
                    ea_deletePL(PL(group));
                end
            end
            
            
            delete(app)


        end

        % Value changed function: MinimumAmplitudeEditField
        function MinimumAmplitudeEditFieldValueChanged(app, event)
            value = app.MinimumAmplitudeEditField.Value;
            app.enablerules;
            app.draw;
        end

        % Value changed function: MaximumAmplitudeEditField
        function MaximumAmplitudeEditFieldValueChanged(app, event)
            value = app.MaximumAmplitudeEditField.Value;
            app.enablerules;
            app.draw;
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            value = app.StepSizeEditField.Value;
            app.enablerules;
            app.draw;
        end

        % Value changed function: ConstCurrDropdown
        function ConstCurrDropdownValueChanged(app, event)
            value = app.ConstCurrDropdown.Value;
            app.enablerules;
            app.draw;
        end

        % Button pushed function: PredictOutcomesButton
        function PredictOutcomesButtonPushed(app, event)
            app.draw(1,'predict');
        end

        % Selection changed function: PatientSelectionButtonGroup
        function PatientSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.PatientSelectionButtonGroup.SelectedObject;
            app.enablerules;
        end

        % Menu selected function: OptimizeStimulationvolumecalculationsMenu
        function OptimizeStimulationvolumecalculationsMenuSelected(app, event)
            if isempty(app.tractset)
                d=load(app.fibfiltmodelpath,'-mat');
                app.tractset=d.tractset;
                clear d
            end

            tractsetclone=ea_disctract;
            app.tractset.copyobj(tractsetclone);
            % tractsetclone.ID = 'retest3';
            patlist=app.patientlist;
            ea_cleartune_optimize(tractsetclone,patlist,app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.OptimizeStimulationvolumecalculationsMenu = uimenu(app.ToolsMenu);
            app.OptimizeStimulationvolumecalculationsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptimizeStimulationvolumecalculationsMenuSelected, true);
            app.OptimizeStimulationvolumecalculationsMenu.Text = 'Optimize Stimulation volume calculations';
            app.ChooseFiberfilteringModelButton = uibutton(app.UIFigure, 'push');
            app.ChooseFiberfilteringModelButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFiberfilteringModelButtonPushed, true);
            app.ChooseFiberfilteringModelButton.Text = 'Choose Fiberfiltering Model';
            app.ChooseNetworkMappingModelButton = uibutton(app.UIFigure, 'push');
            app.ChooseNetworkMappingModelButton.Enable = 'off';
            app.ChooseNetworkMappingModelButton.Text = 'Choose Network Mapping Model';
            app.ChooseSweetspotMappingModelButton = uibutton(app.UIFigure, 'push');
            app.ChooseSweetspotMappingModelButton.Enable = 'off';
            app.ChooseSweetspotMappingModelButton.Text = 'Choose Sweetspot Mapping Model';
            app.SymptomWeightsPanel = uipanel(app.UIFigure);
            app.SymptomWeightsPanel.Title = 'Symptom Weights';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SuggestStimulationParametersTab = uitab(app.TabGroup);
            app.SuggestStimulationParametersTab.Title = 'Suggest Stimulation Parameters';
            app.ConstCurrDropdown = uidropdown(app.SuggestStimulationParametersTab);
            app.ConstCurrDropdown.Items = {'mA', 'V'};
            app.ConstCurrDropdown.ValueChangedFcn = createCallbackFcn(app, @ConstCurrDropdownValueChanged, true);
            app.ConstCurrDropdown.Value = 'mA';
            app.SuggestStimulationParametersButton = uibutton(app.SuggestStimulationParametersTab, 'push');
            app.SuggestStimulationParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SuggestStimulationParametersButtonPushed, true);
            app.SuggestStimulationParametersButton.Text = 'Suggest Stimulation Parameters';
            app.VisualizeResultsCheckBox = uicheckbox(app.SuggestStimulationParametersTab);
            app.VisualizeResultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualizeResultsCheckBoxValueChanged, true);
            app.VisualizeResultsCheckBox.Text = 'Visualize Results';
            app.VisualizeResultsCheckBox.Value = true;
            app.AutoRefreshCheckBox = uicheckbox(app.SuggestStimulationParametersTab);
            app.AutoRefreshCheckBox.Text = 'Auto Refresh';
            app.MinimumAmplitudeEditFieldLabel = uilabel(app.SuggestStimulationParametersTab);
            app.MinimumAmplitudeEditFieldLabel.Text = 'Minimum Amplitude';
            app.MinimumAmplitudeEditField = uieditfield(app.SuggestStimulationParametersTab, 'numeric');
            app.MinimumAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumAmplitudeEditFieldValueChanged, true);
            app.MinimumAmplitudeEditField.Value = 1;
            app.MaximumAmplitudeEditFieldLabel = uilabel(app.SuggestStimulationParametersTab);
            app.MaximumAmplitudeEditFieldLabel.Text = 'Maximum Amplitude';
            app.MaximumAmplitudeEditField = uieditfield(app.SuggestStimulationParametersTab, 'numeric');
            app.MaximumAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumAmplitudeEditFieldValueChanged, true);
            app.MaximumAmplitudeEditField.Value = 5;
            app.StepSizeEditFieldLabel = uilabel(app.SuggestStimulationParametersTab);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.SuggestStimulationParametersTab, 'numeric');
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.StepSizeEditField.Value = 0.5;
            app.PredictOutcomesforExistingParametersTab = uitab(app.TabGroup);
            app.PredictOutcomesforExistingParametersTab.Title = 'Predict Outcomes for Existing Parameters';
            app.PredictOutcomesButton = uibutton(app.PredictOutcomesforExistingParametersTab, 'push');
            app.PredictOutcomesButton.ButtonPushedFcn = createCallbackFcn(app, @PredictOutcomesButtonPushed, true);
            app.PredictOutcomesButton.Text = 'Predict Outcomes';
            app.StimulationSettingDropDownLabel = uilabel(app.PredictOutcomesforExistingParametersTab);
            app.StimulationSettingDropDownLabel.Text = 'Stimulation Setting';
            app.StimulationSettingDropDown = uidropdown(app.PredictOutcomesforExistingParametersTab);
            app.PatientSelectionButtonGroup = uibuttongroup(app.UIFigure);
            app.PatientSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PatientSelectionButtonGroupSelectionChanged, true);
            app.PatientSelectionButtonGroup.Title = 'Patient Selection';
            app.SelectexternalPatientsButton = uiradiobutton(app.PatientSelectionButtonGroup);
            app.SelectexternalPatientsButton.Text = 'Select external Patients:';
            app.SelectexternalPatientsButton.Value = true;
            app.UsepatientsfrommodelfilesleaveoneoutButton = uiradiobutton(app.PatientSelectionButtonGroup);
            app.UsepatientsfrommodelfilesleaveoneoutButton.Text = 'Use patients from model files (leave-one-out)';
            app.ChoosePatientFoldersButton = uibutton(app.PatientSelectionButtonGroup, 'push');
            app.ChoosePatientFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosePatientFoldersButtonPushed, true);
            app.ChoosePatientFoldersButton.Text = 'Choose Patient Folder(s)...';
            app.BusyLampLabel = uilabel(app.PatientSelectionButtonGroup);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.PatientSelectionButtonGroup);
            app.UsepatientsfrommodelfilesleavenothingoutButton = uiradiobutton(app.PatientSelectionButtonGroup);
            app.UsepatientsfrommodelfilesleavenothingoutButton.Text = 'Use patients from model files (leave-nothing-out)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_cleartune(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end