classdef ea_score_gen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ToolsMenu                   matlab.ui.container.Menu
        GenerateExampleExcelMenu    matlab.ui.container.Menu
        forUPDRSMenu                matlab.ui.container.Menu
        forOtherScoretypesMenu      matlab.ui.container.Menu
        ExportCleanedExcelMenu      matlab.ui.container.Menu
        forUPDRSMenu_2              matlab.ui.container.Menu
        forotherscoresMenu          matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        ImportPostopExcelButton     matlab.ui.control.Button
        GenerateTemplateExcelforpostopSwitch  matlab.ui.control.Switch
        GenerateTemplateExcelforpostopSwitchLabel  matlab.ui.control.Label
        GenerateTemplateExcelforbaselineSwitch  matlab.ui.control.Switch
        GenerateTemplateExcelforbaselineSwitchLabel  matlab.ui.control.Label
        TransformbaselinescoresDropDown  matlab.ui.control.DropDown
        TransformbaselinescoresDropDownLabel  matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        BusyLamp                    matlab.ui.control.Lamp
        BusyLampLabel               matlab.ui.control.Label
        ModelSetupDropDown          matlab.ui.control.DropDown
        ModelSetupDropDownLabel     matlab.ui.control.Label
        UITable2                    matlab.ui.control.Table
        UITable                     matlab.ui.control.Table
        GUIDLabel                   matlab.ui.control.Label
        ThresholdScoresatEditField  matlab.ui.control.NumericEditField
        ThresholdScoresatEditFieldLabel  matlab.ui.control.Label
        ImportBaselineExcelButton   matlab.ui.control.Button
        LateralityButtonGroup       matlab.ui.container.ButtonGroup
        LeftButton                  matlab.ui.control.RadioButton
        RightButton                 matlab.ui.control.RadioButton
        BilateralButton             matlab.ui.control.RadioButton
        PostopColumnStartEditFieldLabel  matlab.ui.control.Label
        GENERATEButton              matlab.ui.control.Button
    end

  properties (Access = public)
        
        lead_file
        ex_excel
        fname_preop
        fname_postop
        tdata_preop
        tdata_postop
        baseline_start_medoff
        baseline_stop_medoff
        postop_start_medoff
        postop_stop_medoff
        BASET
        POSTT
        patients
        global_score
        brady
        rigidity
        axial
        tremor
        hndl
        postopFlag
        baselineFlag
        GLOBAL_BASELINE
        GLOBAL_ABS_VALS
        GLOBAL_AVG_VALS
        GLOBAL_PERC_VALS
        GLOBAL_CLEANED_VALS
        BRADYKINESIA_BASELINE
        BRADYKINESIA_ABS_VALS
        BRADYKINESIA_PERC_VALS
        BRADYKINESIA_L_ABS_VALS
        BRADYKINESIA_L_PERC_VALS
        BRADYKINESIA_L_BASELINE
        BRADYKINESIA_R_ABS_VALS
        BRADYKINESIA_R_PERC_VALS
        BRADYKINESIA_R_BASELINE
        BRADYKINESIA_CLEANED_VALS
        RIGIDITY_BASELINE
        RIGIDITY_ABS_VALS
        RIGIDITY_PERC_VALS
        RIGIDITY_L_ABS_VALS
        RIGIDITY_L_PERC_VALS
        RIGIDITY_L_BASELINE
        RIGIDITY_R_ABS_VALS
        RIGIDITY_R_PERC_VALS
        RIGIDITY_R_BASELINE
        RIGIDITY_CLEANED_VALS
        AXIAL_BASELINE
        AXIAL_ABS_VALS
        AXIAL_CLEANED_VALS
        AXIAL_PERC_VALS
        TREMOR_BASELINE
        TREMOR_ABS_VALS
        TREMOR_PERC_VALS
        TREMOR_L_ABS_VALS
        TREMOR_L_PERC_VALS
        TREMOR_L_BASELINE
        TREMOR_R_ABS_VALS
        TREMOR_R_PERC_VALS
        TREMOR_R_BASELINE
        TREMOR_CLEANED_VALS
        CUSTOM_BASELINE
        CUSTOM_abs_imp
        CUSTOM_perc_imp
        guid
    end
    
    
    methods (Access = private)
        
        function score_generator(app,baseline_range,postop_range)
            %get baseline table names
            baseline_tab = readtable(app.fname_preop,'Range',baseline_range);
            baseline_mat = readmatrix(app.fname_preop,'Range',baseline_range);
            baseline_tab_names = baseline_tab.Properties.VariableNames;
            %sometimes, the first row is all NaNs in the imported excelt
            %Therefore, check for this condition and remove the row if not
            %needed
            if all(isnan(baseline_mat(1,:)))
                baseline_mat(1,:) = [];
            end
            %get postop table names
            postop_tab = readtable(app.fname_postop,'Range',postop_range);
            postop_mat = readmatrix(app.fname_postop,'Range',postop_range);
            postop_tab_names = postop_tab.Properties.VariableNames;
            if all(isnan(postop_mat(1,:)))
                postop_mat(1,:) = [];
            end
            %make a baseline table and a postop table
            app.BASET = array2table(baseline_mat,'VariableNames',baseline_tab_names);
            app.POSTT = array2table(postop_mat,'VariableNames',postop_tab_names);


            if strcmp(app.ModelSetupDropDown.Value,'Calculate as UPDRS subscores')
                calculate_as_updrs_subscores(app)
            end
            if strcmp(app.ModelSetupDropDown.Value,'Calculate only change scores')
                calculate_as_change_score(app)
            end
            to_disp = ['*' 'Scores saved successfully for all patients' '*'];
            disp(to_disp)
        end

        function calculate_as_updrs_subscores(app)
            somatotopy = app.LateralityButtonGroup.SelectedObject;
            for pt=1:length(app.patients)
                %if it is a MDS UPDRS or UPDRS. It will make a difference
                %in which variables make up which scores.
                [~,subjid,~] = fileparts(app.patients);
                postop_flag = app.EditField.Value;
                baseline_flag = 'baseline';
                BASET_pt = app.BASET(pt,:);
                POSTT_pt = app.POSTT(pt,:);
                total_available = regexpi(BASET_pt.Properties.VariableNames{end}, '.*Total|Total.*','once');
                odir=fullfile(app.lead_file.patient.list{pt},'clinical',app.guid);
                ofile = fullfile(odir,[subjid{pt},'_desc-clinicalScores.mat']);
                %make output directory if it does not exist.
                if ~exist(ofile,'file')
                    mkdir(odir);
                else
                    load(ofile);
                end
                if isempty(total_available) || all(BASET_pt{:,end}==0) || all(BASET_pt{:,end}=='NaN')
                    %if you have to calculate the total, add up
                    %already total names do not
                    %consider column names which are "total". MAYBE
                    %THIS CAN BE CHANGED?
                    exclude_preop = cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*Total|Total.*'));
                    exclude_postop = cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, '.*Total|Total.*'));
                    global_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(exclude_preop));
                    global_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(exclude_postop));
                    global_preop.Total = ea_nansum(global_preop{:,1:end},2);
                    global_postop.Total = ea_nansum(global_postop{:,1:end},2);
                    app.GLOBAL_BASELINE.(postop_flag)(pt,:) = global_preop{:,end};
                    %output the raw values, absolute values and perc
                    %improvements
                    clinical.(app.guid).scores.(baseline_flag).Global.raw_values = global_preop;
                    if strcmp(app.TransformbaselinescoresDropDown.Value,'None')
                        clinical.(app.guid).scores.(baseline_flag).Global.total_baseline = global_preop.Total;
                    end
                    clinical.(app.guid).scores.(postop_flag).Global.raw_values = global_postop;
                    clinical.(app.guid).scores.(postop_flag).Global.abs_improvements = global_preop.Total - global_postop.Total;
                    clinical.(app.guid).scores.(postop_flag).Global.perc_improvements = (global_preop.Total - global_postop.Total)./global_preop.Total;
                    
                else
                    %output the raw values, absolute values and perc
                    %improvements
                    clinical.(app.guid).scores.(baseline_flag).Global.raw_values = BASET_pt;
                    if strcmp(app.TransformbaselinescoresDropDown.Value,'None')
                        clinical.(app.guid).scores.(baseline_flag).Global.total_baseline = BASET_pt.Total;
                    end
                    clinical.(app.guid).scores.(postop_flag).Global.raw_values = POSTT_pt;
                    clinical.(app.guid).scores.(postop_flag).Global.abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                    clinical.(app.guid).scores.(postop_flag).Global.perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                    app.GLOBAL_BASELINE.(postop_flag)(pt,:) = BASET_pt{:,end};
                end
                %global absolute and improvement
                app.GLOBAL_ABS_VALS.(postop_flag)(pt,:) = clinical.(app.guid).scores.(postop_flag).Global.abs_improvements;
                app.GLOBAL_PERC_VALS.(postop_flag)(pt,:) = clinical.(app.guid).scores.(postop_flag).Global.perc_improvements;

                %%bradykinesia
                if strcmp(somatotopy.Text,'Left')
                    hasMatch_preop_br = [];
                    hasMatch_postop_br = [];
                    hasMatch_preop_br_L = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Left|ToeTappingL'));
                    hasMatch_postop_br_L = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6].*Left|ToeTappingL'));
                    hasMatch_preop_br_R = [];
                    hasMatch_postop_br_R = [];
                    
                elseif strcmp(somatotopy.Text,'Right')
                    hasMatch_preop_br = [];
                    hasMatch_postop_br = [];
                    hasMatch_preop_br_R = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Right|ToeTappingR'));
                    hasMatch_postop_br_R = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6].*Right|ToeTappingR'));
                    hasMatch_preop_br_L = [];
                    hasMatch_postop_br_L = [];
                    
                else
                    hasMatch_preop_br = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames,'U.*[2][3-6]|ToeTapping'));
                    hasMatch_postop_br = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames,'U.*[2][3-6]|ToeTapping'));
                    hasMatch_preop_br_L = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames,'U.*[2][3-6].*Left|ToeTappingL'));
                    hasMatch_preop_br_R = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames,'U.*[2][3-6].*Right|ToeTappingR'));                    
                    hasMatch_postop_br_L = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames,'U.*[2][3-6].*Left|ToeTappingL'));
                    hasMatch_postop_br_R = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames,'U.*[2][3-6].*Right|ToeTappingR'));
                end
                if any(hasMatch_preop_br)
                    symp = 'BRADYKINESIA';
                    clinical = createPreopValues(app,hasMatch_preop_br,clinical,symp,pt);
                end
                if any(hasMatch_preop_br_L)
                    symp = 'BRADYKINESIA_L';
                    clinical = createPreopValues(app,hasMatch_preop_br_L,clinical,symp,pt);
                end
                if any(hasMatch_preop_br_R)
                    symp = 'BRADYKINESIA_R';
                    clinical = createPreopValues(app,hasMatch_preop_br_R,clinical,symp,pt);
                end
                if any(hasMatch_postop_br)
                    symp = 'BRADYKINESIA';
                    clinical = createPostopValues(app,hasMatch_postop_br,clinical,symp,pt);
                end
                if any(hasMatch_postop_br_L)
                    symp = 'BRADYKINESIA_L';
                    clinical = createPostopValues(app,hasMatch_postop_br_L,clinical,symp,pt);
                end
                if any(hasMatch_postop_br_R)
                    symp = 'BRADYKINESIA_R';
                    clinical = createPostopValues(app,hasMatch_postop_br_R,clinical,symp,pt);
                end


                %%rigidity
                if strcmp(somatotopy.Text,'Left')
                    hasMatch_preop_rig = [];
                    hasMatch_postop_rig = [];
                    hasMatch_preop_rig_L = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22.*Left'));
                    hasMatch_postop_rig_L = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*22.*Left'));
                    hasMatch_preop_rig_R = [];
                    hasMatch_postop_rig_R = [];
                
                elseif strcmp(somatotopy.Text,'Right')
                    hasMatch_preop_rig = [];
                    hasMatch_postop_rig = [];
                    hasMatch_preop_rig_R = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22.*Right'));
                    hasMatch_postop_rig_R = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*22.*Right'));
                    hasMatch_preop_rig_L = [];
                    hasMatch_postop_rig_L = [];

                else
                    hasMatch_preop_rig = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22'));
                    hasMatch_postop_rig = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*22'));
                    hasMatch_preop_rig_L = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22.*Left'));
                    hasMatch_postop_rig_L = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*22.*Left'));
                    hasMatch_preop_rig_R = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22.*Right'));
                    hasMatch_postop_rig_R = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*22.*Right'));
                end

                if any(hasMatch_preop_rig)
                    symp = 'RIGIDITY';
                    clinical = createPreopValues(app,hasMatch_preop_rig,clinical,symp,pt);
                end
                if any(hasMatch_preop_rig_L)
                    symp = 'RIGIDITY_L';
                    clinical = createPreopValues(app,hasMatch_preop_rig_L,clinical,symp,pt);
                end
                if any(hasMatch_preop_rig_R)
                    symp = 'RIGIDITY_R';
                    clinical = createPreopValues(app,hasMatch_preop_rig_R,clinical,symp,pt);
                end

                if any(hasMatch_postop_rig)
                    symp = 'RIGIDITY';
                    clinical = createPostopValues(app,hasMatch_postop_rig,clinical,symp,pt);
                end
                if any(hasMatch_postop_rig_L)
                    symp = 'RIGIDITY_L';
                    clinical = createPostopValues(app,hasMatch_postop_rig_L,clinical,symp,pt);
                end
                if any(hasMatch_postop_rig_R)
                    symp = 'RIGIDITY_R';
                    clinical = createPostopValues(app,hasMatch_postop_rig_R,clinical,symp,pt);
                end

                %%axial and gait scores
                hasMatch_preop_ax = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[1-2][7-9]|U.*30|U.*31|MDSFOG'));
                hasMatch_postop_ax = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[1-2][7-9]|U.*30|U.*31|MDSFOG'));
                if any(hasMatch_preop_ax)
                    symp = 'AXIAL';
                    clinical = createPreopValues(app,hasMatch_preop_ax,clinical,symp,pt);
                end
                if any(hasMatch_postop_ax)
                    symp = 'AXIAL';
                    clinical = createPostopValues(app,hasMatch_postop_ax,clinical,symp,pt);
                end

                %%tremor
                if strcmp(somatotopy.Text,'Left')
                    hasMatch_preop_tr = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Left'));
                    hasMatch_postop_tr = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Left'));
                elseif strcmp(somatotopy.Text,'Right')
                    hasMatch_preop_tr = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Right'));
                    hasMatch_postop_tr = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Right'));
                else
                    hasMatch_preop_tr = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor'));
                    hasMatch_postop_tr = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor'));
                    hasMatch_preop_tr_L = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Left'));
                    hasMatch_postop_tr_L = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Left'));
                    hasMatch_preop_tr_R = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Right'));
                    hasMatch_postop_tr_R = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Right'));
                end

                if any(hasMatch_preop_tr)
                    symp = 'TREMOR';
                    clinical = createPreopValues(app,hasMatch_preop_tr,clinical,symp,pt);
                    if any(hasMatch_preop_tr_L)
                       symp = 'TREMOR_L';
                       clinical = createPreopValues(app,hasMatch_preop_tr_L,clinical,symp,pt);
                    end
                    if any(hasMatch_preop_tr_R)
                       symp = 'TREMOR_R';
                       clinical = createPreopValues(app,hasMatch_preop_tr_R,clinical,symp,pt);
                    end
                end
                if any(hasMatch_postop_tr)
                    symp = 'TREMOR';
                    clinical = createPostopValues(app,hasMatch_postop_tr,clinical,symp,pt);
                    if any(hasMatch_postop_tr_L)
                       symp = 'TREMOR_L';
                       clinical =createPostopValues(app,hasMatch_postop_tr_L,clinical,symp,pt);
                    end
                    if any(hasMatch_preop_tr_R)
                       symp = 'TREMOR_R';
                       clinical = createPostopValues(app,hasMatch_postop_tr_R,clinical,symp,pt);
                    end
                end
                %finally save the values into each patient's directory
                save(ofile,'clinical')
                clear clinical;
            end
            app.GLOBAL_CLEANED_VALS.(postop_flag) = ea_resid(app.GLOBAL_BASELINE.(postop_flag),app.GLOBAL_ABS_VALS.(postop_flag));
            if ~strcmp(app.TransformbaselinescoresDropDown.Value,'None')
                maxVal = max(app.GLOBAL_BASELINE.(postop_flag));
                minVal = min(app.GLOBAL_BASELINE.(postop_flag));
                meanVal = mean(app.GLOBAL_BASELINE.(postop_flag),'omitnan');
                stdVal = std(app.GLOBAL_BASELINE.(postop_flag),'omitnan');
            else
                maxVal = NaN;
                minVal = NaN;
                meanVal = NaN;
                stdVal = NaN;
            end
            saveCleanedImp(app,'Global',maxVal,minVal,meanVal,stdVal)
            if any(hasMatch_preop_br)
                app.BRADYKINESIA_CLEANED_VALS.(postop_flag) = ea_resid(app.BRADYKINESIA_BASELINE.(postop_flag),app.BRADYKINESIA_ABS_VALS.(postop_flag));
                if ~strcmp(app.TransformbaselinescoresDropDown.Value,'None')    
                    maxVal = max(app.BRADYKINESIA_BASELINE.(postop_flag));
                    minVal = min(app.BRADYKINESIA_BASELINE.(postop_flag));
                    meanVal = mean(app.BRADYKINESIA_BASELINE.(postop_flag),'omitnan');
                    stdVal = std(app.BRADYKINESIA_BASELINE.(postop_flag),'omitnan');
                else
                    maxVal = NaN;
                    minVal = NaN;
                    meanVal = NaN;
                    stdVal = NaN;
                end
                saveCleanedImp(app,'bradykinesia',maxVal,minVal,meanVal,stdVal);
            end
            if any(hasMatch_preop_rig)
                app.RIGIDITY_CLEANED_VALS.(postop_flag) = ea_resid(app.RIGIDITY_BASELINE.(postop_flag),app.RIGIDITY_ABS_VALS.(postop_flag));
                if ~strcmp(app.TransformbaselinescoresDropDown.Value,'None')
                    maxVal = max(app.RIGIDITY_BASELINE.(postop_flag));
                    minVal = min(app.RIGIDITY_BASELINE.(postop_flag));
                    meanVal = mean(app.RIGIDITY_BASELINE.(postop_flag),'omitnan');
                    stdVal = std(app.RIGIDITY_BASELINE.(postop_flag),'omitnan');
                else
                    maxVal = NaN;
                    minVal = NaN;
                    meanVal = NaN;
                    stdVal = NaN;
                end
                saveCleanedImp(app,'rigidity',maxVal,minVal,meanVal,stdVal)
            end
            if any(hasMatch_preop_ax)
                app.AXIAL_CLEANED_VALS.(postop_flag) = ea_resid(app.AXIAL_BASELINE.(postop_flag),app.AXIAL_ABS_VALS.(postop_flag));
                if ~strcmp(app.TransformbaselinescoresDropDown.Value,'None')    
                    maxVal = max(app.AXIAL_BASELINE.(postop_flag));
                    minVal = min(app.AXIAL_BASELINE.(postop_flag));
                    meanVal = mean(app.AXIAL_BASELINE.(postop_flag),'omitnan');
                    stdVal = std(app.AXIAL_BASELINE.(postop_flag),'omitnan');
                else
                    maxVal = NaN;
                    minVal = NaN;
                    meanVal = NaN;
                    stdVal = NaN;
                end
                saveCleanedImp(app,'axial',maxVal,minVal,meanVal,stdVal)
            end
            if any(hasMatch_preop_tr)
                app.TREMOR_CLEANED_VALS.(postop_flag) = ea_resid(app.TREMOR_BASELINE.(postop_flag),app.TREMOR_ABS_VALS.(postop_flag));
                if ~strcmp(app.TransformbaselinescoresDropDown.Value,'None')
                    maxVal = max(app.TREMOR_BASELINE.(postop_flag));
                    minVal = min(app.TREMOR_BASELINE.(postop_flag));
                    meanVal = mean(app.TREMOR_BASELINE.(postop_flag),'omitnan');
                    stdVal = std(app.TREMOR_BASELINE.(postop_flag),'omitnan');
                else
                    maxVal = NaN;
                    minVal = NaN;
                    meanVal = NaN;
                    stdVal = NaN;
                end
                saveCleanedImp(app,'tremor',maxVal,minVal,meanVal,stdVal);
            end
            function clinical = createPreopValues(app,hasMatch_preop,clinical,symp,pt)
                BASET_pt = app.BASET(pt,:);
                baseline_flag = 'baseline';
                tab_val = [symp,'_BASELINE'];
                if contains(symp,'_L')
                    symp = strrep(symp,'_L','');
                    scoreName = [lower(symp),'Left'];
                elseif contains(symp,'_R')
                    symp = strrep(symp,'_R','');
                    scoreName = [lower(symp),'Right'];
                else
                    scoreName = [lower(symp)];
                end
                preopTab = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                preopTab.Total = ea_nansum(preopTab{:,1:end},2);
                clinical.(app.guid).scores.(baseline_flag).(scoreName).raw_values = preopTab;
                app.(tab_val).(postop_flag)(pt,:) = preopTab{:,end};
                if strcmp(app.TransformbaselinescoresDropDown.Value,'None')
                    clinical.(app.guid).scores.(baseline_flag).(scoreName).normalized_baseline = preopTab.Total;
                end
                return 
            end
            function clinical = createPostopValues(app,hasMatch_postop,clinical,symp,pt)
                POSTT_pt = app.POSTT(pt,:);
                postop_flag = app.EditField.Value;
                tab_val_preop = [symp,'_BASELINE'];
                tab_val_abs = [symp,'_ABS_VALS'];
                tab_val_perc = [symp,'_PERC_VALS'];
                if contains(symp,'_L')
                    symp = strrep(symp,'_L','');
                    scoreName = [lower(symp),'Left'];
                elseif contains(symp,'_R')
                    symp = strrep(symp,'_R','');
                    scoreName = [lower(symp),'Right'];
                else
                    scoreName = [lower(symp)];
                end
                preopVal = app.(tab_val_preop).(postop_flag)(pt,:);
                postopTab = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                postopTab.Total = ea_nansum(postopTab{:,1:end},2);
                if strcmp(symp,'TREMOR')
                    if app.ThresholdScoresatEditField.Value
                        if preopVal < app.ThresholdScoresatEditField.Value
                            postopTab.Total = NaN;
                            clinical.(app.guid).scores.(postop_flag).(scoreName).(['abs_improvements_thresholded' num2str(app.ThresholdScoresatEditField.Value)]) = NaN;
                            clinical.(app.guid).scores.(postop_flag).(scoreName).(['perc_improvements_thresholded' num2str(app.ThresholdScoresatEditField.Value)]) = NaN;
                            app.(tab_val_abs).(postop_flag)(pt,:) = clinical.(app.guid).scores.(postop_flag).(scoreName).(['abs_improvements_thresholded' num2str(app.ThresholdScoresatEditField.Value)]);
                            app.(tab_val_perc).(postop_flag)(pt,:) = clinical.(app.guid).scores.(postop_flag).(scoreName).(['perc_improvements_thresholded' num2str(app.ThresholdScoresatEditField.Value)]);
                            app.(tab_val_preop).(postop_flag)(pt,:) = NaN;
                        else
                            clinical.(app.guid).scores.(postop_flag).(scoreName).raw_values = postopTab;
                            clinical.(app.guid).scores.(postop_flag).(scoreName).(['abs_improvements_thresholded' num2str(app.ThresholdScoresatEditField.Value)]) = preopVal - postopTab.Total;
                            clinical.(app.guid).scores.(postop_flag).(scoreName).(['perc_improvements_thresholded' num2str(app.ThresholdScoresatEditField.Value)]) = (preopVal-postopTab.Total)./preopVal;
                            app.(tab_val_abs).(postop_flag)(pt,:) = clinical.(app.guid).scores.(postop_flag).(scoreName).(['abs_improvements_thresholded' num2str(app.ThresholdScoresatEditField.Value)]);
                            app.(tab_val_perc).(postop_flag)(pt,:) = clinical.(app.guid).scores.(postop_flag).(scoreName).(['perc_improvements_thresholded' num2str(app.ThresholdScoresatEditField.Value)]);
                        end
                    else
                        clinical.(app.guid).scores.(postop_flag).(scoreName).raw_values = postopTab;
                        clinical.(app.guid).scores.(postop_flag).(scoreName).abs_improvements = preopVal - postopTab.Total;
                        clinical.(app.guid).scores.(postop_flag).(scoreName).perc_improvements = (preopVal-postopTab.Total)./preopVal;
                        app.(tab_val_abs).(postop_flag)(pt,:) = clinical.(app.guid).scores.(postop_flag).(scoreName).abs_improvements;
                        app.(tab_val_perc).(postop_flag)(pt,:) = clinical.(app.guid).scores.(postop_flag).(scoreName).perc_improvements;
                    end
                else
                    clinical.(app.guid).scores.(postop_flag).(scoreName).raw_values = postopTab;
                    clinical.(app.guid).scores.(postop_flag).(scoreName).abs_improvements = preopVal - postopTab.Total;
                    clinical.(app.guid).scores.(postop_flag).(scoreName).perc_improvements = (preopVal-postopTab.Total)./preopVal;
                    app.(tab_val_abs).(postop_flag)(pt,:) = clinical.(app.guid).scores.(postop_flag).(scoreName).abs_improvements;
                    app.(tab_val_perc).(postop_flag)(pt,:) = clinical.(app.guid).scores.(postop_flag).(scoreName).perc_improvements;
                end
                return
            end
            function saveCleanedImp(app,scoreName,maxVal,minVal,meanVal,stdVal)
                [~,subjid,~] = fileparts(app.patients);
                postop_flag = app.EditField.Value;
                baseline_flag = 'baseline';
                scoreNameTable = [upper(scoreName),'_CLEANED_VALS'];
                scoreNameNormBaseline = [upper(scoreName),'_BASELINE'];
                for pt = 1:length(app.patients)
                    currVal = app.(scoreNameNormBaseline).(postop_flag)(pt,:);
                    odir=fullfile(app.lead_file.patient.list{pt},'clinical',app.guid);
                    %make output directory if it does not exist.
                    ofile = fullfile(odir,[subjid{pt},'_desc-clinicalScores.mat']);
                    if ~exist(ofile,'file')
                        mkdir(odir);
                    else
                        load(ofile,'clinical');
                    end
                    if strcmp(app.TransformbaselinescoresDropDown.Value,'Divide by maximum')
                        normalizedVal = currVal/maxVal;
                        normalizedLab = 'normalized_baseline';
                    elseif strcmp(app.TransformbaselinescoresDropDown.Value,'Min-max normalization')
                        normalizedVal = (currVal - minVal)./(maxVal);
                        normalizedLab = 'normalized_baseline';
                    elseif strcmp(app.TransformbaselinescoresDropDown.Value,'Standardization')
                        normalizedVal = (currVal - meanVal)./(stdVal);
                        normalizedLab = 'normalized_baseline';
                    else
                        normalizedVal = currVal;
                        normalizedLab = 'total_baseline';
                    end
                    clinical.(app.guid).scores.(baseline_flag).(scoreName).(normalizedLab) = normalizedVal;
                    clinical.(app.guid).scores.(postop_flag).(scoreName).cleaned_improvements = app.(scoreNameTable).(postop_flag)(pt,:);
                    save(ofile,'clinical')
                    clear clinical
                end
            end
        end

        function calculate_as_change_score(app)
            for pt=1:length(app.patients)
                %if it is a MDS UPDRS or UPDRS. It will make a difference
                %in which variables make up which scores.
                [~,subjid,~] = fileparts(app.patients);
                postop_flag = app.EditField.Value;
                baseline_flag = app.EditField_2.Value;
                BASET_pt = app.BASET(pt,:);
                POSTT_pt = app.POSTT(pt,:);
                odir=fullfile(app.lead_file.patient.list{pt},'clinical',app.guid);
                ofile = fullfile(odir,[subjid{pt},'_desc-clinicalScores.mat']);
                %make output directory if it does not exist.
                if ~exist(ofile,'file')
                    mkdir(odir);
                else
                    load(ofile);
                end
                %n_scores = length(BASET_pt.Properties.VariableNames);
                baseline_score_name = BASET_pt.Properties.VariableNames{1};
                postop_score_name = POSTT_pt.Properties.VariableNames{1};
                preopscore = BASET_pt.(baseline_score_name);
                postopscore = POSTT_pt.(postop_score_name);
                clinical.(app.guid).scores.(baseline_flag).(baseline_score_name).raw_values = preopscore;
                clinical.(app.guid).scores.(postop_flag).(baseline_score_name).raw_values = postopscore;
                clinical.(app.guid).scores.(postop_flag).(baseline_score_name).abs_improvement = preopscore - postopscore;
                clinical.(app.guid).scores.(postop_flag).(baseline_score_name).perc_improvement = (preopscore - postopscore)/(preopscore);
                app.CUSTOM_abs_imp.(baseline_score_name)(pt,:) = preopscore - postopscore;
                app.CUSTOM_perc_imp.(baseline_score_name)(pt,:) = (preopscore - postopscore)/preopscore;
            
                save(ofile,'clinical');
                clear clinical;
            end
        end

        function column_names = read_tab(app,tab)

            alpha1 = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
            diffAmt = length(tab.Properties.VariableNames) - length(alpha1);
            if diffAmt < 0
                for i=1:length(tab.Properties.VariableNames)
                    column_names{i} = [alpha1(i) ' :' tab.Properties.VariableNames{i}];
                end
            else
                for i=1:length(alpha1)
                    column_names{i} = [alpha1(i) ' :' tab.Properties.VariableNames{i}];
                end
                j = 1;
                for i=length(alpha1)+1:length(alpha1)+diffAmt
                    column_names{i} = [alpha1(1),alpha1(j) ' :' tab.Properties.VariableNames{i}];
                    j = j+1;
                end
                
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, excel_sheet_pre, lead_group, handles)
            app.UIFigure.Name = 'Clinical Score Generator';
            if ~strcmp(excel_sheet_pre,'')
               app.fname = excel_sheet_pre; 
               app.tdata = readtable(app.fname);
            end
            app.lead_file = lead_group;
            app.patients = app.lead_file.patient.list;
            app.hndl = handles;
            app.GUIDLabel.Text = ['GUID: ' app.lead_file.guid];
            %other stuff in the custom tab

        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            app.forUPDRSMenu_2.Enable = 'on';
            app.forUPDRSMenu.Enable = 'on';
            app.BusyLamp.Color = 'red';
            %app.patients = app.lead_file.patient.list;
            app.guid = ['gs_' app.lead_file.guid];
            baseline_range = [app.baseline_start_medoff ':' app.baseline_stop_medoff]; 
            postop_range = [app.postop_start_medoff ':' app.postop_stop_medoff]; 
            score_generator(app,baseline_range,postop_range);
            disp("Process Done **")
            setappdata(app.hndl.leadfigure,'M',app.lead_file);
            app.BusyLamp.Color = 'green';
                
        end

        % Menu selected function: forUPDRSMenu
        function forUPDRSMenuSelected(app, event)
            app.ex_excel = [ea_getearoot, '/clinical_score_generator/example_excel_UPDRS.xlsx'];
            patient_tab{:} = app.lead_file.patient.list;
            example_excel = readtable(app.ex_excel);
            arr = zeros(length(app.lead_file.patient.list),(size(example_excel,2)-1));
            arr_cell = num2cell(arr);
            pre_excel = [patient_tab{1,1} arr_cell];           
            excel_tab = array2table(pre_excel,'VariableNames',example_excel.Properties.VariableNames);
            [file,path] = uiputfile('clinical_scores.xlsx');
            writetable(excel_tab,fullfile(path,file));
            %app.fname = fullfile(path,file);
        end

        % Button pushed function: ImportBaselineExcelButton
        function ImportBaselineExcelButtonPushed(app, event)
            if strcmp(event.Source.Type,'uiswitch')
                app.fname_preop = event.Source.Value;
            else
                [file,path] = uigetfile('*.xlsx');
                app.fname_preop = fullfile(path,file); 
            end
            if exist(app.fname_preop,'file')
                app.ImportBaselineExcelButton.Text = file;
                app.tdata_preop = readtable(app.fname_preop);
                app.ThresholdScoresatEditField.Enable = 'on';
                app.ThresholdScoresatEditFieldLabel.Enable = 'on';
                app.LateralityButtonGroup.Enable = 'on';
                app.GENERATEButton.Enable = 'on';
                app.LateralityButtonGroup.Enable = 'on';
                column_names_preop = read_tab(app,app.tdata_preop);
                app.UITable.ColumnName = column_names_preop;
                app.UITable.Data = app.tdata_preop;
                if strcmp(app.ModelSetupDropDown.Value,'Calculate as UPDRS subscores')
                    preselectupdrs_preop(app)
                end
                scroll(app.UITable,'right');
                app.TransformbaselinescoresDropDown.Enable = 'On';
            else
                ea_warndlg('Could not load file, please try again');
            end
             
            function preselectupdrs_preop(app)
                n_patients = length(app.lead_file.patient.list);
                k = 1;
                for j=1:n_patients
                    a(k:k+32,1) = repmat(j,33,1);
                    k = k+33;
                end
                %for preop scores
                preopSelection = [a,repmat(linspace(2,34,33)',n_patients,1)];
                app.UITable.Selection = preopSelection;
                preopStyle = uistyle('BackgroundColor','#4DBEEE');
                addStyle(app.UITable,preopStyle,'column',(2:35));
                pattern = ':(.*_?)[A-Za-z0-9]*';
                %for preop scores
                PreopRows = app.UITable.Selection;
                newPreopRows = unique(PreopRows(:,2));
                newPreopData = app.UITable.ColumnName(newPreopRows,:);
                start_string_preop = newPreopData{1};
                stop_string_preop = newPreopData{end};
                app.baseline_start_medoff = regexprep(start_string_preop,pattern,'');
                app.baseline_stop_medoff = regexprep(stop_string_preop,pattern,'');
            end
        end

        % Menu selected function: forOtherScoretypesMenu
        function forOtherScoretypesMenuSelected(app, event)
            app.ex_excel = [ea_getearoot, 'clinical_score_generator/example_excel.xlsx'];
            patient_tab{:} = app.lead_file.patient.list;
            example_excel = readtable(app.ex_excel);
            arr = zeros(length(app.lead_file.patient.list),(size(example_excel,2)-1));
            arr_cell = num2cell(arr);
            pre_excel = [patient_tab{1,1} arr_cell];
            clm_names{1} = 'PatientID';
            for i=2:length(example_excel.Properties.VariableNames)
                clm_names{i} = ['Var' num2str(i)];
            end
            
            excel_tab = array2table(pre_excel,'VariableNames',clm_names);
            [file,path] = uiputfile('clinical_scores.xlsx');
            writetable(excel_tab,fullfile(path,file));
            %app.fname = fullfile(path,file);
        end

        % Menu selected function: ExportCleanedExcelMenu
        function ExportCleanedExcelMenuSelected(app, event)

            
        end

        % Callback function
        function MaxMinNormalizationMenuSelected(app, event)
            flag = 3;
            postopid = app.BASELINEEditField.Value;
            if ischar(app.baseline_start_medoff) && ischar(app.baseline_stop_medoff)
                postop_range = [app.baseline_start_medoff ':' app.baseline_stop_medoff];
            else
                postop_range = [1,app.baseline_start_medoff,length(app.patients)+1,app.baseline_stop_medoff];
            end
            baseline_generator(app,postopid,postop_range,flag)
        end

        % Callback function
        function StandardizationMenuSelected(app, event)
            flag = 4;
            postopid = app.BASELINEEditField.Value;
            if ischar(app.baseline_start_medoff) && ischar(app.baseline_stop_medoff)
                postop_range = [app.baseline_start_medoff ':' app.baseline_stop_medoff];
            else
                postop_range = [1,app.baseline_start_medoff,length(app.patients)+1,app.baseline_stop_medoff];
            end
            baseline_generator(app,postopid,postop_range,flag)
            
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            rows = event.Selection;
            %since multi select is on we should only get the unique values
            newRows = unique(rows(:,2));
            newData = app.UITable.ColumnName(newRows,:);
            pattern = ':(.*_?)[A-Za-z0-9]*';
            start_string = newData{1};
            stop_string = newData{end};
            app.baseline_start_medoff = regexprep(start_string,pattern,'');
            app.baseline_stop_medoff = regexprep(stop_string,pattern,'');
        end

        % Selection changed function: UITable2
        function UITable2SelectionChanged(app, event)
            rows = event.Selection;
            %since multi select is on we should only get the unique values
            newRows = unique(rows(:,2));
            newData = app.UITable2.ColumnName(newRows,:);
            pattern = ':(.*_?)[A-Za-z0-9]*';
            start_string = newData{1};
            stop_string = newData{end};
            app.postop_start_medoff = regexprep(start_string,pattern,'');
            app.postop_stop_medoff = regexprep(stop_string,pattern,'');
        end

        % Value changed function: ModelSetupDropDown
        function ModelSetupDropDownValueChanged(app, event)
            if strcmp(app.ModelSetupDropDown.Value,'Calculate as UPDRS subscores')
                preselectupdrs(app)
            else
                app.UITable.Selection = [];
                app.UITable2.Selection = [];
                preopStyle = uistyle('BackgroundColor','w');
                postopStyle = uistyle('BackgroundColor','w');
                addStyle(app.UITable,preopStyle,'column',(2:35));
                addStyle(app.UITable2,postopStyle,'column',(37:70));
            end
            function preselectupdrs(app)
                n_patients = length(app.lead_file.patient.list);
                
                k = 1;
                for j=1:n_patients
                    a(k:k+33,1) = repmat(j,34,1);
                    k = k+34;
                end
                %for preop scores
                preopSelection = [a,repmat(linspace(2,35,34)',n_patients,1)];
                %for postop scores
                postopSelection = [a,repmat(linspace(37,70,34)',n_patients,1)];
                
                app.UITable.Selection = preopSelection;
                app.UITable2.Selection = postopSelection;
                preopStyle = uistyle('BackgroundColor','#4DBEEE');
                postopStyle = uistyle('BackgroundColor','#77AC30');
                addStyle(app.UITable,preopStyle,'column',(2:35));
                addStyle(app.UITable2,postopStyle,'column',(37:70));
            end

            pattern = ':(.*_?)[A-Za-z0-9]*';
            %for preop scores
            PreopRows = app.UITable.Selection;
            if ~isempty(PreopRows)
                newPreopRows = unique(PreopRows(:,2));
                newPreopData = app.UITable.ColumnName(newPreopRows,:);
                start_string_preop = newPreopData{1};
                stop_string_preop = newPreopData{end};
                app.baseline_start_medoff = regexprep(start_string_preop,pattern,'');
                app.baseline_stop_medoff = regexprep(stop_string_preop,pattern,'');
            end

            %for postop scores
            PostopRows = app.UITable2.Selection;
            %since multi select is on we should only get the unique values
            if ~isempty(PostopRows)
                newPostopRows = unique(PostopRows(:,2));
                newPostopData = app.UITable2.ColumnName(newPostopRows,:);
                start_string_postop = newPostopData{1};
                stop_string_postop = newPostopData{end};
                app.postop_start_medoff = regexprep(start_string_postop,pattern,'');
                app.postop_stop_medoff = regexprep(stop_string_postop,pattern,'');
            end
        end

        % Value changed function: ThresholdScoresatEditField
        function ThresholdScoresatEditFieldValueChanged(app, event)
            value = app.ThresholdScoresatEditField.Value;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            indices = event.Indices;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged2(app, event)
            value = app.EditField.Value;
            app.postopFlag = app.EditField.Value;
        end

        % Callback function: not associated with a component
        function EditField_2ValueChanged2(app, event)
            value = app.EditField_2.Value;
            app.baselineFlag = app.EditField_2.Value;
        end

        % Drop down opening function: ModelSetupDropDown
        function ModelSetupDropDownOpening(app, event)
            
        end

        % Menu selected function: forUPDRSMenu_2
        function forUPDRSMenu_2Selected2(app, event)
            fields = fieldnames(app.GLOBAL_ABS_VALS);
            for i=1:length(fields)
                global_abs_table = array2table(app.GLOBAL_ABS_VALS.(fields{i}),'VariableNames',{['Global Absolute Improvements' '_' fields{i}]});
                global_imp_table = array2table(app.GLOBAL_PERC_VALS.(fields{i}),'VariableNames',{['Global Percentage Improvements' '_' fields{i}]});
                global_cleaned_tables = array2table(app.GLOBAL_CLEANED_VALS.(fields{i}),'VariableNames',{['Global Cleaned Improvements' '_' fields{i}]});
                bradykinesia_abs_table = array2table(app.BRADYKINESIA_ABS_VALS.(fields{i}),'VariableNames',{['Bradykinesia Absolute Improvements' '_' fields{i}]});
                bradykinesia_imp_table = array2table(app.BRADYKINESIA_PERC_VALS.(fields{i}),'VariableNames',{['Bradykinesia Percentage Improvements' '_' fields{i}]});
                bradykinesia_cleaned_tables = array2table(app.BRADYKINESIA_CLEANED_VALS.(fields{i}),'VariableNames',{['Bradykinesia Cleaned Improvements' '_' fields{i}]});
                rigidity_abs_table = array2table(app.RIGIDITY_ABS_VALS.(fields{i}),'VariableNames',{['Rigidity Absolute Improvements' '_' fields{i}]});
                rigidity_imp_table = array2table(app.RIGIDITY_PERC_VALS.(fields{i}),'VariableNames',{['Rigidity Percentage Improvements' '_' fields{i}]});
                rigidity_cleaned_tables = array2table(app.RIGIDITY_CLEANED_VALS.(fields{i}),'VariableNames',{['Rigidity Cleaned Improvements' '_' fields{i}]});
                axial_abs_table = array2table(app.AXIAL_ABS_VALS.(fields{i}),'VariableNames',{['Axial Absolute Improvements' '_' fields{i}]});
                axial_imp_table = array2table(app.AXIAL_PERC_VALS.(fields{i}),'VariableNames',{['Axial Percentage Improvements' '_' fields{i}]});
                axial_cleaned_tables = array2table(app.AXIAL_CLEANED_VALS.(fields{i}),'VariableNames',{['Axial Cleaned Improvements' '_' fields{i}]});
                tremor_abs_table = array2table(app.TREMOR_ABS_VALS.(fields{i}),'VariableNames',{['Tremor Absolute Improvements' '_' fields{i}]});
                tremor_imp_table = array2table(app.TREMOR_PERC_VALS.(fields{i}),'VariableNames',{['Tremor Percentage Improvements' '_' fields{i}]});
                tremor_cleaned_tables = array2table(app.TREMOR_CLEANED_VALS.(fields{i}),'VariableNames',{['Tremor Cleaned Improvements' '_' fields{i}]});

            end
            patient_list = array2table(app.lead_file.patient.list,'VariableNames',{'Patients'});
            subscores_table = [patient_list,bradykinesia_abs_table,bradykinesia_imp_table,bradykinesia_cleaned_tables,rigidity_abs_table,rigidity_imp_table,rigidity_cleaned_tables,axial_abs_table,axial_imp_table,axial_cleaned_tables,tremor_abs_table,tremor_imp_table,tremor_cleaned_tables,global_abs_table,global_imp_table,global_cleaned_tables];
            writetable(subscores_table,uiputfile('calculated_clinical_scores.xlsx'));


        end

        % Menu selected function: forotherscoresMenu
        function forotherscoresMenuSelected(app, event)
            fields = fieldnames(app.CUSTOM_abs_imp);
            patient_list = array2table(app.lead_file.patient.list,'VariableNames',{'Patients'});
            custom_abs_table = array2table(app.CUSTOM_abs_imp.(fields{1}),'VariableNames',{[fields{1} '_' 'Absolute Improvements']});
            custom_perc_table = array2table(app.CUSTOM_perc_imp.(fields{1}),'VariableNames',{[fields{1} '_' 'Percentage Improvements']});
            full_table = [patient_list,custom_abs_table,custom_perc_table];
            writetable(full_table,uiputfile('calculated_clinical_scores.xlsx'));


        end

        % Value changed function: TransformbaselinescoresDropDown
        function TransformbaselinescoresDropDownValueChanged(app, event)
            
        end

        % Callback function
        function GenerateTemplateExcelDropDownValueChanged(app, event)
            value = app.GenerateTemplateExcelDropDown.Value;
            app.ex_excel = [ea_getearoot, '/clinical_score_generator/example_excel_UPDRS_baseline.xlsx'];
            patient_tab{:} = app.lead_file.patient.list;
            example_excel = readtable(app.ex_excel);
            arr = zeros(length(app.lead_file.patient.list),(size(example_excel,2)-1));
            arr_cell = num2cell(arr);
            pre_excel = [patient_tab{1,1} arr_cell];           
            excel_tab = array2table(pre_excel,'VariableNames',example_excel.Properties.VariableNames);
            [file,path] = uiputfile('clinical_scores.xlsx');
            writetable(excel_tab,fullfile(path,file));
            %app.fname = fullfile(path,file);
        end

        % Value changed function: GenerateTemplateExcelforbaselineSwitch
        function GenerateTemplateExcelforbaselineSwitchValueChanged(app, event)
            value = app.GenerateTemplateExcelforbaselineSwitch.Value;
            if strcmp(value,'for UPDRS')
                app.ex_excel = [ea_getearoot, '/clinical_score_generator/example_excel_UPDRS_baseline.xlsx'];
                op_filename = 'clinical_score_UPDRS.xlsx';
            else
               app.ex_excel = [ea_getearoot, '/clinical_score_generator/example_excel_baseline.xlsx'];
               op_filename = 'clinical_score_other.xlsx';
            end
            if ~isempty(op_filename)
                patient_tab{:} = app.lead_file.patient.list;
                example_excel = readtable(app.ex_excel);
                arr = zeros(length(app.lead_file.patient.list),(size(example_excel,2)-1));
                arr_cell = num2cell(arr);
                pre_excel = [patient_tab{1,1} arr_cell];
                excel_tab = array2table(pre_excel,'VariableNames',example_excel.Properties.VariableNames);
                [file,path] = uiputfile(op_filename);
                if file ~= 0
                    writetable(excel_tab,fullfile(path,file));
                end
            end
        end

        % Value changed function: GenerateTemplateExcelforpostopSwitch
        function GenerateTemplateExcelforpostopSwitchValueChanged(app, event)
            value = app.GenerateTemplateExcelforpostopSwitch.Value;
            if strcmp(value,'for UPDRS')
                app.ex_excel = [ea_getearoot, '/clinical_score_generator/example_excel_UPDRS_postop.xlsx'];
                op_filename = 'clinical_score_UPDRS.xlsx';
            else
               app.ex_excel = [ea_getearoot, '/clinical_score_generator/example_excel_postop.xlsx'];
               op_filename = 'clinical_score_other.xlsx';
            end
            if ~isempty(op_filename)
                patient_tab{:} = app.lead_file.patient.list;
                example_excel = readtable(app.ex_excel);
                arr = zeros(length(app.lead_file.patient.list),(size(example_excel,2)-1));
                arr_cell = num2cell(arr);
                pre_excel = [patient_tab{1,1} arr_cell];
                excel_tab = array2table(pre_excel,'VariableNames',example_excel.Properties.VariableNames);
                [file,path] = uiputfile(op_filename);
                if file ~= 0
                    writetable(excel_tab,fullfile(path,file));
                end
            end
        end

        % Button pushed function: ImportPostopExcelButton
        function ImportPostopExcelButtonPushed(app, event)
            if strcmp(event.Source.Type,'uiswitch')
                app.fname_postop = event.Source.Value;
            else
                [file,path] = uigetfile('*.xlsx');
                app.fname_postop = fullfile(path,file); 
            end
            if exist(app.fname_postop,'file')
                app.ImportPostopExcelButton.Text = file;
                app.tdata_postop = readtable(app.fname_postop);
                app.ThresholdScoresatEditField.Enable = 'on';
                app.ThresholdScoresatEditFieldLabel.Enable = 'on';
                app.LateralityButtonGroup.Enable = 'on';
                app.GENERATEButton.Enable = 'on';
                app.LateralityButtonGroup.Enable = 'on';
                column_names_postop = read_tab(app,app.tdata_postop);
                app.UITable2.ColumnName = column_names_postop;
                app.UITable2.Data = app.tdata_postop;
                if strcmp(app.ModelSetupDropDown.Value,'Calculate as UPDRS subscores')
                    preselectupdrs_postop(app)
                end
                scroll(app.UITable2,'right');
                app.TransformbaselinescoresDropDown.Enable = 'On';
            else
                ea_warndlg('Could not load file, please try again');
            end
             
            function preselectupdrs_postop(app)
                n_patients = length(app.lead_file.patient.list);
                k = 1;
                for j=1:n_patients
                    a(k:k+32,1) = repmat(j,33,1);
                    k = k+33;
                end
                %for preop scores
                postopSelection = [a,repmat(linspace(2,34,33)',n_patients,1)];
                app.UITable2.Selection = postopSelection;
                postopStyle = uistyle('BackgroundColor','#4DBEEE');
                addStyle(app.UITable2,postopStyle,'column',(2:35));
                pattern = ':(.*_?)[A-Za-z0-9]*';
                %for postop scores
                PostopRows = app.UITable2.Selection;
                %since multi select is on we should only get the unique values
                newPostopRows = unique(PostopRows(:,2));
                newPostopData = app.UITable2.ColumnName(newPostopRows,:);
                start_string_postop = newPostopData{1};
                stop_string_postop = newPostopData{end};
                app.postop_start_medoff = regexprep(start_string_postop,pattern,'');
                app.postop_stop_medoff = regexprep(stop_string_postop,pattern,'');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.GenerateExampleExcelMenu = uimenu(app.ToolsMenu);
            app.GenerateExampleExcelMenu.Text = 'Generate Example Excel';
            app.forUPDRSMenu = uimenu(app.GenerateExampleExcelMenu);
            app.forUPDRSMenu.MenuSelectedFcn = createCallbackFcn(app, @forUPDRSMenuSelected, true);
            app.forUPDRSMenu.Text = 'for UPDRS';
            app.forOtherScoretypesMenu = uimenu(app.GenerateExampleExcelMenu);
            app.forOtherScoretypesMenu.MenuSelectedFcn = createCallbackFcn(app, @forOtherScoretypesMenuSelected, true);
            app.forOtherScoretypesMenu.Text = 'for Other Score types';
            app.ExportCleanedExcelMenu = uimenu(app.ToolsMenu);
            app.ExportCleanedExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportCleanedExcelMenuSelected, true);
            app.ExportCleanedExcelMenu.Text = 'Export Cleaned Excel';
            app.forUPDRSMenu_2 = uimenu(app.ExportCleanedExcelMenu);
            app.forUPDRSMenu_2.MenuSelectedFcn = createCallbackFcn(app, @forUPDRSMenu_2Selected2, true);
            app.forUPDRSMenu_2.Text = 'for UPDRS';
            app.forotherscoresMenu = uimenu(app.ExportCleanedExcelMenu);
            app.forotherscoresMenu.MenuSelectedFcn = createCallbackFcn(app, @forotherscoresMenuSelected, true);
            app.forotherscoresMenu.Text = 'for other scores';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.GENERATEButton = uibutton(app.Tab, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Enable = 'off';
            app.GENERATEButton.Text = 'GENERATE';
            app.PostopColumnStartEditFieldLabel = uilabel(app.Tab);
            app.PostopColumnStartEditFieldLabel.Text = '';
            app.LateralityButtonGroup = uibuttongroup(app.Tab);
            app.LateralityButtonGroup.Enable = 'off';
            app.LateralityButtonGroup.Title = 'Laterality';
            app.BilateralButton = uiradiobutton(app.LateralityButtonGroup);
            app.BilateralButton.Text = 'Bilateral';
            app.BilateralButton.Value = true;
            app.RightButton = uiradiobutton(app.LateralityButtonGroup);
            app.RightButton.Text = 'Right';
            app.LeftButton = uiradiobutton(app.LateralityButtonGroup);
            app.LeftButton.Text = 'Left';
            app.ImportBaselineExcelButton = uibutton(app.Tab, 'push');
            app.ImportBaselineExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportBaselineExcelButtonPushed, true);
            app.ImportBaselineExcelButton.Text = 'Import Baseline Excel';
            app.ThresholdScoresatEditFieldLabel = uilabel(app.Tab);
            app.ThresholdScoresatEditFieldLabel.Enable = 'off';
            app.ThresholdScoresatEditFieldLabel.Text = 'Threshold Scores at';
            app.ThresholdScoresatEditField = uieditfield(app.Tab, 'numeric');
            app.ThresholdScoresatEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdScoresatEditFieldValueChanged, true);
            app.ThresholdScoresatEditField.Enable = 'off';
            app.GUIDLabel = uilabel(app.Tab);
            app.GUIDLabel.Text = 'GUID: ';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'; 'Column 11'; 'Column 12'; 'Column 13'; 'Column 14'; 'Column 15'; 'Column 16'; 'Column 17'; 'Column 18'; 'Column 19'; 'Column 20'; 'Column 21'; 'Column 22'; 'Column 23'; 'Column 24'; 'Column 25'; 'Column 26'; 'Column 27'; 'Column 28'; 'Column 29'; 'Column 30'; 'Column 31'; 'Column 32'; 'Column 33'; 'Column 34'; 'Column 35'; 'Column 36'; 'Column 37'; 'Column 38'; 'Column 39'; 'Column 40'; 'Column 41'; 'Column 42'; 'Column 43'; 'Column 44'; 'Column 45'; 'Column 46'; 'Column 47'; 'Column 48'; 'Column 49'; 'Column 50'; 'Column 51'; 'Column 52'; 'Column 53'; 'Column 54'; 'Column 55'; 'Column 56'; 'Column 57'; 'Column 58'; 'Column 59'; 'Column 60'; 'Column 61'; 'Column 62'; 'Column 63'; 'Column 64'; 'Column 65'; 'Column 66'; 'Column 67'; 'Column 68'; 'Column 69'; 'Column 70'; 'Column 71'; 'Column 72'; 'Column 73'; 'Column 74'; 'Column 75'; 'Column 76'; 'Column 77'; 'Column 78'; 'Column 79'; 'Column 80'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable2 = uitable(app.Tab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'; 'Column 11'; 'Column 12'; 'Column 13'; 'Column 14'; 'Column 15'; 'Column 16'; 'Column 17'; 'Column 18'; 'Column 19'; 'Column 20'; 'Column 21'; 'Column 22'; 'Column 23'; 'Column 24'; 'Column 25'; 'Column 26'; 'Column 27'; 'Column 28'; 'Column 29'; 'Column 30'; 'Column 31'; 'Column 32'; 'Column 33'; 'Column 34'; 'Column 35'; 'Column 36'; 'Column 37'; 'Column 38'; 'Column 39'; 'Column 40'; 'Column 41'; 'Column 42'; 'Column 43'; 'Column 44'; 'Column 45'; 'Column 46'; 'Column 47'; 'Column 48'; 'Column 49'; 'Column 50'; 'Column 51'; 'Column 52'; 'Column 53'; 'Column 54'; 'Column 55'; 'Column 56'; 'Column 57'; 'Column 58'; 'Column 59'; 'Column 60'; 'Column 61'; 'Column 62'; 'Column 63'; 'Column 64'; 'Column 65'; 'Column 66'; 'Column 67'; 'Column 68'; 'Column 69'; 'Column 70'; 'Column 71'; 'Column 72'; 'Column 73'; 'Column 74'; 'Column 75'; 'Column 76'; 'Column 77'; 'Column 78'; 'Column 79'; 'Column 80'};
            app.UITable2.RowName = {};
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.UITable2.SelectionChangedFcn = createCallbackFcn(app, @UITable2SelectionChanged, true);
            app.ModelSetupDropDownLabel = uilabel(app.Tab);
            app.ModelSetupDropDownLabel.Text = 'Model Setup';
            app.ModelSetupDropDown = uidropdown(app.Tab);
            app.ModelSetupDropDown.Items = {'Calculate as UPDRS subscores', 'Calculate as other subscores', 'Calculate only change scores'};
            app.ModelSetupDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ModelSetupDropDownOpening, true);
            app.ModelSetupDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSetupDropDownValueChanged, true);
            app.ModelSetupDropDown.Value = 'Calculate as UPDRS subscores';
            app.BusyLampLabel = uilabel(app.Tab);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.Tab);
            app.EditField = uieditfield(app.Tab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged2, true);
            app.EditField.Value = 'sixMonthsPostop';
            app.TransformbaselinescoresDropDownLabel = uilabel(app.Tab);
            app.TransformbaselinescoresDropDownLabel.Enable = 'off';
            app.TransformbaselinescoresDropDownLabel.Text = 'Transform baseline scores';
            app.TransformbaselinescoresDropDown = uidropdown(app.Tab);
            app.TransformbaselinescoresDropDown.Items = {'Divide by maximum', 'Min-max normalization', 'Standardization', 'None'};
            app.TransformbaselinescoresDropDown.ValueChangedFcn = createCallbackFcn(app, @TransformbaselinescoresDropDownValueChanged, true);
            app.TransformbaselinescoresDropDown.Enable = 'off';
            app.TransformbaselinescoresDropDown.Value = 'Divide by maximum';
            app.GenerateTemplateExcelforbaselineSwitchLabel = uilabel(app.Tab);
            app.GenerateTemplateExcelforbaselineSwitchLabel.Text = 'Generate Template Excel for baseline ';
            app.GenerateTemplateExcelforbaselineSwitch = uiswitch(app.Tab, 'slider');
            app.GenerateTemplateExcelforbaselineSwitch.Items = {'for other score types', 'for UPDRS'};
            app.GenerateTemplateExcelforbaselineSwitch.ValueChangedFcn = createCallbackFcn(app, @GenerateTemplateExcelforbaselineSwitchValueChanged, true);
            app.GenerateTemplateExcelforbaselineSwitch.Value = 'for other score types';
            app.GenerateTemplateExcelforpostopSwitchLabel = uilabel(app.Tab);
            app.GenerateTemplateExcelforpostopSwitchLabel.Text = 'Generate Template Excel for postop ';
            app.GenerateTemplateExcelforpostopSwitch = uiswitch(app.Tab, 'slider');
            app.GenerateTemplateExcelforpostopSwitch.Items = {'for other score types', 'for UPDRS'};
            app.GenerateTemplateExcelforpostopSwitch.ValueChangedFcn = createCallbackFcn(app, @GenerateTemplateExcelforpostopSwitchValueChanged, true);
            app.GenerateTemplateExcelforpostopSwitch.Value = 'for other score types';
            app.ImportPostopExcelButton = uibutton(app.Tab, 'push');
            app.ImportPostopExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPostopExcelButtonPushed, true);
            app.ImportPostopExcelButton.Text = 'Import Postop Excel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_score_gen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end