classdef ea_discfiberexplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ExportasAtlasButton         matlab.ui.control.Button
        AnalysisIDEditFieldLabel    matlab.ui.control.Label
        AnalysisIDEditField         matlab.ui.control.EditField
        LeadGroupPathLabel          matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        GeneralModelSetupPanel      matlab.ui.container.Panel
        CalculateButton             matlab.ui.control.Button
        ConnthresholdSlider         matlab.ui.control.Slider
        ConnthresholdLabel          matlab.ui.control.Label
        ModelSetupDropDownLabel     matlab.ui.control.Label
        ModelSetupDropDown          matlab.ui.control.DropDown
        ConnectomeDropDownLabel     matlab.ui.control.Label
        ConnectomeDropDown          matlab.ui.control.DropDown
        InteractiveSetupPanel       matlab.ui.container.Panel
        IncludeallpatientsCheckBox  matlab.ui.control.CheckBox
        MirrorSidesCheckBox         matlab.ui.control.CheckBox
        ColorButtonPositive         matlab.ui.control.Button
        ColorButtonNegative         matlab.ui.control.Button
        ShowPositiveFibersCheckBox  matlab.ui.control.CheckBox
        ShowNegativeFibersCheckBox  matlab.ui.control.CheckBox
        SplitColorbyGroupVariableCheckBox  matlab.ui.control.CheckBox
        ColorBar                    matlab.ui.control.UIAxes
        PatientsListBoxLabel        matlab.ui.control.Label
        PatientsListBox             matlab.ui.control.ListBox
        ShowAmountSliderLabel       matlab.ui.control.Label
        ShowAmountSliderPositive    matlab.ui.control.Slider
        LeftSliderLabel             matlab.ui.control.Label
        LeftSliderPositive          matlab.ui.control.Slider
        RightSliderLabel            matlab.ui.control.Label
        RightSliderPositive         matlab.ui.control.Slider
        LeftSlider_2Label           matlab.ui.control.Label
        LeftSliderNegative          matlab.ui.control.Slider
        ShowAmountSlider_2Label     matlab.ui.control.Label
        ShowAmountSliderNegative    matlab.ui.control.Slider
        RightSlider_2Label          matlab.ui.control.Label
        RightSliderNegative         matlab.ui.control.Slider
        VariableofInterestDropDownLabel  matlab.ui.control.Label
        VariableofInterestDropDown  matlab.ui.control.DropDown
        CovariatesListBoxLabel      matlab.ui.control.Label
        CovariatesListBox           matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        tractset % associated ea_discriminativetractset object.
        M % Lead group analysis model
    end
    
    methods (Access = private)
        
        function results = updateConnthresholdSlider(app,changingValue)
            value = app.ModelSetupDropDown.Value;
            switch value
                case 'T-Tests / VTAs' % ttests
                    app.ConnthresholdLabel.Text = {'Tracts should be connected to at',['least ',sprintf('%.1f',changingValue),' % of VTAs']};
                    app.ConnthresholdSlider.Limits = [0 100];
                    app.ConnthresholdSlider.Value = 20;
                case 'Spearman''s Correlation / Efields' % efield
                    app.ConnthresholdLabel.Text={'Tracts are modulated at E-Field',['strength of ',sprintf('%.1f',changingValue),' V/m']};
                    app.ConnthresholdSlider.Limits = [0 1000];
                    app.ConnthresholdSlider.Value = 150;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lgpath, resultfig)
            app.UIFigure.Name='Discriminative Fibers Explorer';
            
            % Add modlist (structural connectomes)
            [mdl,sf]=ea_genmodlist;
            app.ConnectomeDropDown.Items=mdl(sf==1);
            
            % Add standard colors for tracts
            app.ColorButtonPositive.BackgroundColor='r';
            app.ColorButtonNegative.BackgroundColor='b';
            
            % Add Value to connthreshsliderlabel
            changingValue=app.ConnthresholdSlider.Value;
            updateConnthresholdSlider(app,changingValue);
            if ~exist('resultfig','var')
                resultfig = gcf;
            end
            
            % use supplied information to setup analysis
            if ~exist('lgpath','var')
                [FileName,PathName]=uigetfile('LEAD_groupanalysis.mat','Please Choose Lead-Group Analysis project...');
                pobj.leadgrouppath = fullfile(PathName,FileName);
            else
                pobj.leadgrouppath = lgpath;
            end
            D=load(pobj.leadgrouppath); % produces M
            if isfield(D,'M') % Lead Group analysis path loaded
                
                app.M=D.M;
                clear D
                % Update GUI:
                app.OpenLeadGroupProjectDiscriminativeFibertractButton.Text=pobj.leadgrouppath;
                
                app.PatientsListBox.Items=app.M.patient.list;
                app.VariableofInterestDropDown.Items=app.M.clinical.labels;
                app.CovariatesListBox.Items=['Stimulation Amplitude','VTA Volume',app.M.clinical.labels];
                [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
                app.CovariatesListBox.Items(ix)=[];
                app.PatientsListBox.Value=app.PatientsListBox.Items; % select all
                
                % initialize tract object
                
                app.tractset = ea_disctract;
                app.tractset.M = app.M;
                app.tractset.resultfig = resultfig; % change
                app.tractset.ID = app.M.guid; % change
                app.tractset.allpatients = app.M.patient.list;
 
            elseif isfield(D,'tractset') % saved tractobject loaded
               app.tractset = D.tractset;
            else
                ea_error('You have opened a file of unknown type.')
                return
            end
                        
            % enable controls:
            app.ModelSetupDropDown.Enable = 'on';
            app.ConnectomeDropDown.Enable = 'on';
            app.ConnectomeDropDownLabel.Enable = 'on';
            app.IncludeallpatientsCheckBox.Enable = 'on';
            app.MirrorSidesCheckBox.Enable = 'on';
            app.ConnectomeDropDown.Enable = 'on';
            app.VariableofInterestDropDown.Enable = 'on';
            app.CovariatesListBox.Enable = 'on';
            app.SplitColorbyGroupVariableCheckBox.Enable = 'on';
            app.ShowPositiveFibersCheckBox.Enable = 'on';
            app.ShowNegativeFibersCheckBox.Enable = 'on';
            app.ShowAmountSliderPositive.Enable = 'on';
            app.LeftSliderPositive.Enable = 'on';
            app.RightSliderPositive.Enable = 'on';
            app.ColorButtonPositive.Enable = 'on';
            app.CalculateButton.Enable = 'on';
            app.ShowNegativeFibersCheckBox.Enable = 'on';
            
            app.ConnthresholdSlider.Enable = 'on';
            app.ConnthresholdLabel.Enable = 'on';
            app.ModelSetupDropDownLabel.Enable = 'on';
            app.ShowAmountSliderLabel.Enable = 'on';
            app.LeftSliderLabel.Enable = 'on';
            app.RightSliderLabel.Enable = 'on';
            app.VariableofInterestDropDownLabel.Enable = 'on';
            app.CovariatesListBoxLabel.Enable = 'on';
            
            
        end

        % Value changed function: SplitColorbyGroupVariableCheckBox
        function SplitColorbyGroupVariableCheckBoxValueChanged(app, event)
            value = app.SplitColorbyGroupVariableCheckBox.Value;
            switch value
                case 1
                    app.ShowNegativeFibersCheckBox.Enable = 'off';
                    app.ShowNegativeFibersCheckBox.Value = 0;
                    app.ColorButtonNegative.Enable = 'off';
                    app.ShowAmountSliderNegative.Enable = 'off';
                    app.LeftSliderNegative.Enable = 'off';
                    app.RightSliderNegative.Enable = 'off';
                    app.ColorButtonPositive.Enable = 'off';
                    
                    app.ShowAmountSlider_2Label.Enable = 'off';
                    app.LeftSlider_2Label.Enable = 'off';
                    app.RightSlider_2Label.Enable = 'off';
                case 0
                    app.ShowNegativeFibersCheckBox.Enable = 'on';
                    app.ColorButtonPositive.Enable = 'on';
            end
        end

        % Value changed function: ModelSetupDropDown
        function ModelSetupDropDownValueChanged(app, event)
            changingValue=app.ConnthresholdSlider.Value;
            updateConnthresholdSlider(app,changingValue)
        end

        % Value changing function: ConnthresholdSlider
        function ConnthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            updateConnthresholdSlider(app,changingValue)
        end

        % Value changed function: VariableofInterestDropDown
        function VariableofInterestDropDownValueChanged(app, event)
            app.CovariatesListBox.Items=['Stimulation Amplitude','VTA Volume',app.M.clinical.labels];
            [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            figure;
            app.tractset.M=app.M;
            %app.tractset.app=app; % need to change
            app.tractset.leadgroup=app.OpenLeadGroupProjectDiscriminativeFibertractButton.Text;
            
            %% set up object properties:
            app.tractset.posvisible = app.ShowPositiveFibersCheckBox.Value;
            app.tractset.negvisible = app.ShowNegativeFibersCheckBox.Value;
            app.tractset.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.tractset.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];            
            app.tractset.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.tractset.negcolor = app.ColorButtonNegative.BackgroundColor;
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;
            
            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.M.clinical.labels);
            app.tractset.responsevar = app.M.clinical.vars{ix};
            app.tractset.responsevarlabel = app.M.clinical.labels{ix};
            
            [is]=ismember(app.M.clinical.labels,app.CovariatesListBox.Value);
            if any(is)
                app.tractset.covars = app.M.clinical.vars(is);
                app.tractset.covarlabels = app.M.clinical.labels(is);
            end
            
            % Check whether Amplitude or VTA volumes should be
            % included, as well.
            if ismember('Stimulation Amplitude',app.CovariatesListBox.Value)
                warning('This needs to be included.');
            end
            
            if ismember('VTA Volume',app.CovariatesListBox.Value)
                warning('This needs to be included.');
            end
            
            switch app.ModelSetupDropDown.Value
                case 'T-Tests / VTAs'
                    app.tractset.statmetric=1;
                case 'Spearman''s Correlation / Efields'
                    app.tractset.statmetric=2;
            end
            
            app.tractset.connectome = app.M.ui.connectomename;
            app.tractset.connthreshold = app.ConnthresholdSlider.Value;
            app.tractset.mirrorsides = app.MirrorSidesCheckBox.Value;
            
            % calculate general setup
            app.tractset.calculate;
            
            % save dataset:
            app.tractset.save;
            
            % draw results
            app.tractset.draw;
   
            app.ExportasAtlasButton.Enable = 'on';
            
        end

        % Value changed function: IncludeallpatientsCheckBox
        function IncludeallpatientsCheckBoxValueChanged(app, event)
            value = app.IncludeallpatientsCheckBox.Value;
            switch value
                case 1
                    app.PatientsListBox.Enable='off';
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                case 0
                    app.PatientsListBox.Enable='on';
            end
        end

        % Value changed function: ShowPositiveFibersCheckBox
        function ShowPositiveFibersCheckBoxValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox.Value;
            switch value
                case 0
                    app.ShowAmountSliderPositive.Enable = 'off';
                    app.ColorButtonPositive.Enable = 'off';
                    app.LeftSliderPositive.Enable = 'off';
                    app.RightSliderPositive.Enable = 'off';
                    
                    app.ShowAmountSliderLabel.Enable = 'off';
                    app.LeftSliderLabel.Enable = 'off';
                    app.RightSliderLabel.Enable = 'off';
                case 1
                    app.ShowAmountSliderPositive.Enable = 'on';
                    app.ColorButtonPositive.Enable = 'on';
                    app.LeftSliderPositive.Enable = 'on';
                    app.RightSliderPositive.Enable = 'on';
                    
                    app.ShowAmountSliderLabel.Enable = 'on';
                    app.LeftSliderLabel.Enable = 'on';
                    app.RightSliderLabel.Enable = 'on';
            end
            
            
        end

        % Value changed function: ShowNegativeFibersCheckBox
        function ShowNegativeFibersCheckBoxValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox.Value;
            switch value
                case 0
                    app.ShowAmountSliderNegative.Enable = 'off';
                    app.ColorButtonNegative.Enable = 'off';
                    app.LeftSliderNegative.Enable = 'off';
                    app.RightSliderNegative.Enable = 'off';
                    
                    app.ShowAmountSlider_2Label.Enable = 'off';
                    app.LeftSlider_2Label.Enable = 'off';
                    app.RightSlider_2Label.Enable = 'off';
                    
                case 1
                    app.ShowAmountSliderNegative.Enable = 'on';
                    app.ColorButtonNegative.Enable = 'on';
                    app.LeftSliderNegative.Enable = 'on';
                    app.RightSliderNegative.Enable = 'on';
                    
                    app.ShowAmountSlider_2Label.Enable = 'on';
                    app.LeftSlider_2Label.Enable = 'on';
                    app.RightSlider_2Label.Enable = 'on';
            end
        end

        % Value changing function: ShowAmountSliderPositive
        function ShowAmountSliderPositiveValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive.Value = changingValue;
            app.RightSliderPositive.Value = changingValue;
        end

        % Value changing function: ShowAmountSliderNegative
        function ShowAmountSliderNegativeValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative.Value = changingValue;
            app.RightSliderNegative.Value = changingValue;
        end

        % Value changing function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            event.Value=strrep(event.Value,' ','_');
            event.Value=strrep(event.Value,'(','');
            event.Value=strrep(event.Value,')','');
            event.Value=strrep(event.Value,'-','');
            event.Value=strrep(event.Value,'.',''); 
            
            event.Value=strrep(event.Value,'"','');
            event.Value=strrep(event.Value,'~','');
            event.Value=strrep(event.Value,'#','');
            event.Value=strrep(event.Value,'^',''); 
            
            event.Value=strrep(event.Value,'|','');
            event.Value=strrep(event.Value,'$','');
            event.Value=strrep(event.Value,'%','');
            event.Value=strrep(event.Value,'&',''); 
            
            event.Value=strrep(event.Value,'*',''); 
            event.Value=strrep(event.Value,'!',''); 
        end

        % Value changed function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanged(app, event)
            value = app.AnalysisIDEditField.Value;
            if exist([app.tractset.leadgroup,filesep,'disctracts',filesep,app.tractset.ID,'.mat'],'file')
                movefile([app.tractset.leadgroup,filesep,'disctracts',filesep,app.tractset.ID,'.mat'],...
                    [app.tractset.leadgroup,filesep,'disctracts',filesep,value,'.mat'])
            end
            app.tractset.ID=value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ExportasAtlasButton = uibutton(app.UIFigure, 'push');
            app.ExportasAtlasButton.Enable = 'off';
            app.ExportasAtlasButton.Text = 'Export as Atlas';
            app.AnalysisIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnalysisIDEditFieldLabel.Text = 'Analysis ID';
            app.AnalysisIDEditField = uieditfield(app.UIFigure, 'text');
            app.AnalysisIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanged, true);
            app.AnalysisIDEditField.ValueChangingFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanging, true);
            app.AnalysisIDEditField.Value = 'new_tract_analysis';
            app.LeadGroupPathLabel = uilabel(app.UIFigure);
            app.LeadGroupPathLabel.Text = 'Lead-Group Path';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '<';
            app.GeneralModelSetupPanel = uipanel(app.UIFigure);
            app.GeneralModelSetupPanel.Title = 'General Model Setup';
            app.CalculateButton = uibutton(app.GeneralModelSetupPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.ConnthresholdSlider = uislider(app.GeneralModelSetupPanel);
            app.ConnthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanging, true);
            app.ConnthresholdSlider.Enable = 'off';
            app.ConnthresholdSlider.Value = 20;
            app.ConnthresholdLabel = uilabel(app.GeneralModelSetupPanel);
            app.ConnthresholdLabel.Enable = 'off';
            app.ConnthresholdLabel.Text = {'Tracts should be connected '; 'to at least % of VTAs'};
            app.ModelSetupDropDownLabel = uilabel(app.GeneralModelSetupPanel);
            app.ModelSetupDropDownLabel.Enable = 'off';
            app.ModelSetupDropDownLabel.Text = 'Model Setup';
            app.ModelSetupDropDown = uidropdown(app.GeneralModelSetupPanel);
            app.ModelSetupDropDown.Items = {'T-Tests / VTAs', 'Spearman''s Correlation / Efields'};
            app.ModelSetupDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSetupDropDownValueChanged, true);
            app.ModelSetupDropDown.Enable = 'off';
            app.ModelSetupDropDown.Value = 'T-Tests / VTAs';
            app.ConnectomeDropDownLabel = uilabel(app.GeneralModelSetupPanel);
            app.ConnectomeDropDownLabel.Enable = 'off';
            app.ConnectomeDropDownLabel.Text = 'Connectome';
            app.ConnectomeDropDown = uidropdown(app.GeneralModelSetupPanel);
            app.ConnectomeDropDown.Items = {'', ''};
            app.ConnectomeDropDown.Enable = 'off';
            app.ConnectomeDropDown.Value = '';
            app.InteractiveSetupPanel = uipanel(app.UIFigure);
            app.InteractiveSetupPanel.Title = 'Interactive Setup';
            app.IncludeallpatientsCheckBox = uicheckbox(app.InteractiveSetupPanel);
            app.IncludeallpatientsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeallpatientsCheckBoxValueChanged, true);
            app.IncludeallpatientsCheckBox.Enable = 'off';
            app.IncludeallpatientsCheckBox.Text = 'Include all patients';
            app.IncludeallpatientsCheckBox.Value = true;
            app.MirrorSidesCheckBox = uicheckbox(app.InteractiveSetupPanel);
            app.MirrorSidesCheckBox.Enable = 'off';
            app.MirrorSidesCheckBox.Text = 'Mirror VTAs / Efields to contralateral sides';
            app.ColorButtonPositive = uibutton(app.InteractiveSetupPanel, 'push');
            app.ColorButtonPositive.Enable = 'off';
            app.ColorButtonPositive.Text = 'Color';
            app.ColorButtonNegative = uibutton(app.InteractiveSetupPanel, 'push');
            app.ColorButtonNegative.Enable = 'off';
            app.ColorButtonNegative.Text = 'Color';
            app.ShowPositiveFibersCheckBox = uicheckbox(app.InteractiveSetupPanel);
            app.ShowPositiveFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBoxValueChanged, true);
            app.ShowPositiveFibersCheckBox.Enable = 'off';
            app.ShowPositiveFibersCheckBox.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox.Value = true;
            app.ShowNegativeFibersCheckBox = uicheckbox(app.InteractiveSetupPanel);
            app.ShowNegativeFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBoxValueChanged, true);
            app.ShowNegativeFibersCheckBox.Enable = 'off';
            app.ShowNegativeFibersCheckBox.Text = 'Show Negative Fibers';
            app.SplitColorbyGroupVariableCheckBox = uicheckbox(app.InteractiveSetupPanel);
            app.SplitColorbyGroupVariableCheckBox.ValueChangedFcn = createCallbackFcn(app, @SplitColorbyGroupVariableCheckBoxValueChanged, true);
            app.SplitColorbyGroupVariableCheckBox.Enable = 'off';
            app.SplitColorbyGroupVariableCheckBox.Text = 'Split & Color by Group Variable';
            app.ColorBar = uiaxes(app.InteractiveSetupPanel);
            title(app.ColorBar, 'Colorbar')
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.PatientsListBoxLabel = uilabel(app.InteractiveSetupPanel);
            app.PatientsListBoxLabel.Enable = 'off';
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.InteractiveSetupPanel);
            app.PatientsListBox.Multiselect = 'on';
            app.PatientsListBox.Enable = 'off';
            app.PatientsListBox.Value = {'Item 1'};
            app.ShowAmountSliderLabel = uilabel(app.InteractiveSetupPanel);
            app.ShowAmountSliderLabel.Enable = 'off';
            app.ShowAmountSliderLabel.Text = 'Show Amount';
            app.ShowAmountSliderPositive = uislider(app.InteractiveSetupPanel);
            app.ShowAmountSliderPositive.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositiveValueChanging, true);
            app.ShowAmountSliderPositive.Enable = 'off';
            app.ShowAmountSliderPositive.Value = 2;
            app.LeftSliderLabel = uilabel(app.InteractiveSetupPanel);
            app.LeftSliderLabel.Enable = 'off';
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSliderPositive = uislider(app.InteractiveSetupPanel);
            app.LeftSliderPositive.Enable = 'off';
            app.LeftSliderPositive.Value = 2;
            app.RightSliderLabel = uilabel(app.InteractiveSetupPanel);
            app.RightSliderLabel.Enable = 'off';
            app.RightSliderLabel.Text = 'Right';
            app.RightSliderPositive = uislider(app.InteractiveSetupPanel);
            app.RightSliderPositive.Enable = 'off';
            app.RightSliderPositive.Value = 2;
            app.LeftSlider_2Label = uilabel(app.InteractiveSetupPanel);
            app.LeftSlider_2Label.Enable = 'off';
            app.LeftSlider_2Label.Text = 'Left';
            app.LeftSliderNegative = uislider(app.InteractiveSetupPanel);
            app.LeftSliderNegative.Enable = 'off';
            app.LeftSliderNegative.Value = 2;
            app.ShowAmountSlider_2Label = uilabel(app.InteractiveSetupPanel);
            app.ShowAmountSlider_2Label.Enable = 'off';
            app.ShowAmountSlider_2Label.Text = 'Show Amount';
            app.ShowAmountSliderNegative = uislider(app.InteractiveSetupPanel);
            app.ShowAmountSliderNegative.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegativeValueChanging, true);
            app.ShowAmountSliderNegative.Enable = 'off';
            app.ShowAmountSliderNegative.Value = 2;
            app.RightSlider_2Label = uilabel(app.InteractiveSetupPanel);
            app.RightSlider_2Label.Enable = 'off';
            app.RightSlider_2Label.Text = 'Right';
            app.RightSliderNegative = uislider(app.InteractiveSetupPanel);
            app.RightSliderNegative.Enable = 'off';
            app.RightSliderNegative.Value = 2;
            app.VariableofInterestDropDownLabel = uilabel(app.InteractiveSetupPanel);
            app.VariableofInterestDropDownLabel.Enable = 'off';
            app.VariableofInterestDropDownLabel.Text = 'Variable of Interest';
            app.VariableofInterestDropDown = uidropdown(app.InteractiveSetupPanel);
            app.VariableofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableofInterestDropDownValueChanged, true);
            app.VariableofInterestDropDown.Enable = 'off';
            app.CovariatesListBoxLabel = uilabel(app.InteractiveSetupPanel);
            app.CovariatesListBoxLabel.Enable = 'off';
            app.CovariatesListBoxLabel.Text = 'Covariates';
            app.CovariatesListBox = uilistbox(app.InteractiveSetupPanel);
            app.CovariatesListBox.Multiselect = 'on';
            app.CovariatesListBox.Enable = 'off';
            app.CovariatesListBox.Value = {'Item 1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_discfiberexplorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end