classdef ea_calculate_unifiedmapping < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FiberfilteringButtonGroup      matlab.ui.container.ButtonGroup
        BiophysicalModelFamilyButtonGroup  matlab.ui.container.ButtonGroup
        PathwayActionModelingButton    matlab.ui.control.RadioButton
        StimulationVolumesButton       matlab.ui.control.RadioButton
        CalculateinDropDown            matlab.ui.control.DropDown
        CalculateinDropDownLabel       matlab.ui.control.Label
        CalculationMethodButtonGroup   matlab.ui.container.ButtonGroup
        FiberBasedMethodButton         matlab.ui.control.RadioButton
        EFieldBasedMethodButton        matlab.ui.control.RadioButton
        SweetspotButtonGroup           matlab.ui.container.ButtonGroup
        mmLabel                        matlab.ui.control.Label
        SSResolutionEditField          matlab.ui.control.NumericEditField
        SSResolutionEditFieldLabel     matlab.ui.control.Label
        CalculationSettingsPanel       matlab.ui.container.Panel
        GlobalSettingsButtonGroup      matlab.ui.container.ButtonGroup
        VmLabel                        matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.NumericEditField
        ThresholdEditField_3Label      matlab.ui.control.Label
        NetworkmappingButtonGroup      matlab.ui.container.ButtonGroup
        NormalizeFingerprintsCheckBox  matlab.ui.control.CheckBox
        SmoothFingerprintsCheckBox     matlab.ui.control.CheckBox
        ResolutionstructuralDropDown   matlab.ui.control.DropDown
        ResolutionstructuralDropDownLabel  matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
    end

    
    properties (Access = public)
        explorerobj % tractset like object which will save the calculate settings!
    end
    
    
    methods (Access = public)
        
        function results = enable_rules(app)
                app.CalculationMethodButtonGroup.Enable = 'on';
                app.EFieldBasedMethodButton.Enable = 'on';
                app.FiberBasedMethodButton.Enable = 'on';
                app.CalculateinDropDown.Enable = 'on';
                app.BiophysicalModelFamilyButtonGroup.Enable = 'on';
                app.PathwayActionModelingButton.Enable = 'on';
                app.StimulationVolumesButton.Enable = 'on';
                app.ThresholdEditField.Enable = 'on';
                app.ThresholdEditField_3Label.Enable = 'on';
                % app.ResolutionfunctionalDropDown.Enable = 'on';

                % app.ConnectomeDropDown_2Label.Enable = 'off';
                % app.ConnectomeDropDown.Enable = 'off';
                % 
                % app.ConnectomeDropDown_2Label.Enable = 'on';
                % app.ConnectomeDropDown.Enable = 'on';
                % [mdl,sf]=ea_genmodlist;
                % dmri_connectomenames = mdl(sf==1);
                % dmri_mtconnectomenames = mdl(sf==3);
                % app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];
                % app.ResolutionstructuralDropDownLabel.Visible = 'off';
                % app.ResolutionstructuralDropDown.Visible = 'off';
                % app.SSResolutionEditField.Visible = 'off';
                % app.SSResolutionEditFieldLabel.Visible = 'off';
                % app.CalculateinDropDownLabel.Enable = 'on';
                % app.CalculateinDropDown.Enable = 'on';
                % app.FiberfilteringCalculationMethodSwitch.Enable = 'on';
                % app.FiberfilteringCalculationMethodSwitchLabel.Enable = 'on';
                % app.BiophysicalModelFamilyButtonGroup.Enable = 'on';
                % app.PathwayActionModelingButton.Enable = 'on';
                % app.StimulationVolumesButton.Enable = 'on';
                % app.ThresholdEditField.Enable = 'on';
                % app.ThresholdEditField_3Label.Enable = 'on';
                % 
                % app.ConnectomeDropDown_2Label.Enable = 'on';
                % app.ConnectomeDropDown.Enable = 'on';
                % [mdl,sf]=ea_genmodlist;
                % app.ConnectomeDropDown.Items=mdl;
                % [~,ix]=ismember(app.ConnectomeDropDown.Value,mdl);
                % switch sf(ix)
                %     case 1 % structural connectome selected
                %         app.ResolutionstructuralDropDown.Visible = 'on';
                %         app.ResolutionstructuralDropDownLabel.Visible = 'on';
                %     case 2 % functional connectome selected
                %         app.ResolutionstructuralDropDown.Visible = 'off';
                %         app.ResolutionstructuralDropDown.Value = '2 mm';
                % end
                % app.SSResolutionEditField.Visible = 'off';
                % app.SSResolutionEditFieldLabel.Visible = 'off';
                % app.CalculateinDropDownLabel.Enable = 'off';
                % app.CalculateinDropDown.Enable = 'off';
                % app.FiberfilteringCalculationMethodSwitch.Enable = 'off';
                % app.FiberfilteringCalculationMethodSwitchLabel.Enable = 'off';
                % app.BiophysicalModelFamilyButtonGroup.Enable = 'off';
                % app.PathwayActionModelingButton.Enable = 'off';
                % app.StimulationVolumesButton.Enable = 'off';
                % app.ThresholdEditField.Enable = 'on';
                % app.ThresholdEditField_3Label.Enable = 'on';            

        end
    end
    
    methods (Access = private)

        function disableSettings(app)
             % Check sweetspot
            if isfield(app.explorerobj.results, 'sweetspotmapping') && ...
                    ~isempty(app.explorerobj.results.sweetspotmapping)
                % disable Sweetspot controls
                app.SSResolutionEditField.Enable = 'off';
                app.SSResolutionEditFieldLabel.Enable = 'off';
            end
        
            % Check fiberfiltering
            if isfield(app.explorerobj.results, 'fiberfiltering') && ...
                    ~isempty(app.explorerobj.results.fiberfiltering)
                % disable Fiberfiltering controls
                app.CalculationMethodButtonGroup.Enable = 'off';
                app.EFieldBasedMethodButton.Enable = 'off';
                app.FiberBasedMethodButton.Enable = 'off';
                app.CalculateinDropDown.Enable = 'off';
                app.BiophysicalModelFamilyButtonGroup.Enable = 'off';
                app.PathwayActionModelingButton.Enable = 'off';
                app.StimulationVolumesButton.Enable = 'off';
            end
        
            % Check networkmapping
            if isfield(app.explorerobj.results, 'networkmapping') && ...
                    ~isempty(app.explorerobj.results.networkmapping)
                % disable Networkmapping controls
                % app.NormalizeFingerprintsCheckBox.Enable = 'off';
                % app.SmoothFingerprintsCheckBox.Enable = 'off';
                app.ResolutionstructuralDropDown.Enable = 'off';
                app.ResolutionstructuralDropDownLabel.Enable = 'off';
            end
            % app.CalculationMethodButtonGroup.Enable = 'off';
            % app.EFieldBasedMethodButton.Enable = 'off';
            % app.FiberBasedMethodButton.Enable = 'off';
            % app.CalculateinDropDown.Enable = 'off';
            % app.BiophysicalModelFamilyButtonGroup.Enable = 'off';
            % app.PathwayActionModelingButton.Enable = 'off';
            % app.StimulationVolumesButton.Enable = 'off';
            app.ThresholdEditField.Enable = 'off';
            % app.ThresholdEditField_3Label.Enable = 'off';          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, explorer)

            if ~exist('explorer','var')
                ea_error('Something went wrong! try again!');
            else
                app.explorerobj = explorer;
            end
            app.SaveButton.BackgroundColor = [0 1 0];
            %check if something has been saved
            app.UIFigure.Name = 'Calculate Settings';
            initialize_settings(app)

            if app.explorerobj.hasResults
                disableSettings(app);
            end

            function initialize_settings(app)
                % %tools button group
                % if app.explorerobj.calcsettings.selectedTool == 1
                %     app.SweetspotButton.Value = 1;
                % elseif app.explorerobj.calcsettings.selectedTool == 2
                %     app.FiberfilteringButton.Value = 1;
                % elseif app.explorerobj.calcsettings.selectedTool == 3
                %     app.NetworkmappingButton.Value = 1;
                % end
                enable_rules(app);
                %connectome
                % if app.explorerobj.drawTool == 2
                %     if ~isempty(app.explorerobj.calcsettings.fibfilt_connectome)
                %         app.ConnectomeDropDown.Value = app.explorerobj.calcsettings.fibfilt_connectome;
                %     end
                % elseif app.explorerobj.drawTool == 3
                %     if ~isempty(app.explorerobj.calcsettings.netmap_connectome)
                %         app.ConnectomeDropDown.Value = app.explorerobj.calcsettings.netmap_connectome;
                %     end
                % end
                
                %threshold
                app.ThresholdEditField.Value = app.explorerobj.calcsettings.calcthreshold;
                %resolution
                app.ResolutionstructuralDropDown.Value = app.explorerobj.calcsettings.structuralresolution;
                % app.ResolutionfunctionalDropDown.Value = app.explorerobj.calcsettings.functionalresolution;
                %calculate in
                if app.explorerobj.calcsettings.calcspace == 1
                    app.CalculateinDropDown.Value = 'Template Space';
                elseif app.explorerobj.calcsettings.calcspace == 0
                    app.CalculateinDropDown.Value = 'Native Space';
                end
                %Fiberfiltering calculation method
                switch app.explorerobj.calcsettings.calcmethod
                    case 1
                        app.EFieldBasedMethodButton.Value = true;
                        app.FiberBasedMethodButton.Value = false;
                    case 2
                        app.EFieldBasedMethodButton.Value = false;
                        app.FiberBasedMethodButton.Value = true;
                end
                % if app.explorerobj.calcsettings.calcmethod == 1
                %     app.EFieldBasedMethodButton.Value = 1;
                % elseif app.explorerobj.calcsettings.calcmethod == 2
                %     app.EFieldBasedMethodButton.Value = 2;
                % end
                %biophysical model family
                if app.explorerobj.calcsettings.switch_connectivity == 1
                    if app.explorerobj.calcsettings.connectivity_type == 1
                        app.StimulationVolumesButton.Value = 1;
                    elseif app.explorerobj.calcsettings.connectivity_type == 2
                        app.PathwayActionModelingButton.Value = 1;
                    end
                end
                % restore normalize/smooth checkboxes
                if isfield(app.explorerobj.calcsettings, 'normalize_fp')
                    app.NormalizeFingerprintsCheckBox.Value = app.explorerobj.calcsettings.normalize_fp;
                end

                if isfield(app.explorerobj.calcsettings, 'smooth_fp')
                    app.SmoothFingerprintsCheckBox.Value = app.explorerobj.calcsettings.smooth_fp;
                end
            end

        end

        % Selection changed function: SweetspotButtonGroup
        function SweetspotButtonGroupSelectionChanged(app, event)
            selectedButton = app.SweetspotButtonGroup.SelectedObject;
            enable_rules(app);           
        
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % selectedButton = app.SweetspotButtonGroup.SelectedObject;
            % if strcmp(selectedButton.Text,'Sweetspot')
            %     selectedTool = 1;
            % elseif strcmp(selectedButton.Text,'Fiberfiltering')
            %     selectedTool = 2;
            % elseif strcmp(selectedButton.Text,'Networkmapping')
            %     selectedTool = 3;
            % end
            % app.explorerobj.calcsettings.selectedTool = selectedTool;
            % if selectedTool == 2
            %     app.explorerobj.calcsettings.fibfilt_connectome = app.ConnectomeDropDown.Value;
            %     if exist(fullfile(ea_getconnectomebase('dMRI_MultiTract'),app.ConnectomeDropDown.Value),'dir') %this is inside the dMRI_MultiTract folder
            %         if exist(fullfile(ea_getconnectomebase('dmri'),app.ConnectomeDropDown.Value),'dir') %same connectome exists in both
            %             warndlg("The same connectome exists in Multitract format as well as in Singletract format. Please set unique names before continuing. Further results will only reflect Singletract mode.");
            %             app.explorerobj.calcsettings.multi_pathways = 0;
            %         else
            %             %if it only exists in the multitract pathway, then
            %             %check once more for the multiple .mat files and
            %             %set app.tractset.multi_pathways = 1;
            %             mt_connectome = fullfile(ea_getconnectomebase('dMRI_MultiTract'),app.ConnectomeDropDown.Value);
            %             mt_connectome_dir = dir(fullfile(mt_connectome,'*.mat'));
            %             mt_files = {mt_connectome_dir.name};
            %             if length(mt_files) > 1
            %                 app.explorerobj.calcsettings.multi_pathways = 1;
            %             else
            %                 app.explorerobj.calcsettings.multi_pathways = 0;
            %             end
            %         end
            %     else
            %         app.explorerobj.calcsettings.multi_pathways = 0;
            %     end
            % % elseif selectedTool == 3
            %     app.explorerobj.calcsettings.netmap_connectome = app.ConnectomeDropDown.Value;
            % end
           

            % save & close
            setappdata(app.explorerobj.resultfig, 'explorer', app.explorerobj);
            app.SaveButton.BackgroundColor = [1 1 1];
        
            app.explorerobj.save;
        
            delete(app);
        end

        % Callback function
        function FiberfilteringCalculationMethodSwitchValueChanged(app, event)
            value = app.FiberfilteringCalculationMethodSwitch.Value;
            disp(class(app))
            if strcmp(app.FiberfilteringCalculationMethodSwitch.Value,'E-field Based Method')
                app.CalculateinDropDown.Value = 'Template Space';
                app.CalculateinDropDown.Enable = 'on';
                app.explorerobj.calcsettings.calcspace = 1;
                app.explorerobj.calcsettings.calcmethod = 1;
            elseif strcmp(app.FiberfilteringCalculationMethodSwitch.Value,'Fiber Based Method')
                app.CalculateinDropDown.Value = 'Native Space';
                app.CalculateinDropDown.Enable = 'on';
                app.explorerobj.calcsettings.calcspace = 0;
                app.explorerobj.calcsettings.calcmethod = 2;
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.explorerobj.calcsettings.calcthreshold = value; %check this!!!
        end

        % Selection changed function: BiophysicalModelFamilyButtonGroup
        function BiophysicalModelFamilyButtonGroupSelectionChanged(app, event)
            selectedButton = app.BiophysicalModelFamilyButtonGroup.SelectedObject;
            app.explorerobj.calcsettings.switch_connectivity = 1;
            if strcmp(selectedButton,'Stimulation Vol')
                app.explorerobj.calcsettings.connectivity_type = 1;
            elseif strcmp(selectedButton,'PAM')
                app.explorerobj.calcsettings.connectivity_type = 2;
            end
           %TODO - Highly interlinked with the rest of the stats button, so
           %you need to coordinate!
        end

        % Value changed function: ResolutionstructuralDropDown
        function ResolutionstructuralDropDownValueChanged(app, event)
            value = app.ResolutionstructuralDropDown.Value;
            app.explorerobj.calcsettings.resolution = value;
        end

        % Value changed function: CalculateinDropDown
        function CalculateinDropDownValueChanged(app, event)
            value = app.CalculateinDropDown.Value;
            switch value
                case 'Template Space'
                    app.CalculationMethodButtonGroup.SelectedObject = ...
                        app.EFieldBasedMethodButton;
        
                    app.explorerobj.calcsettings.calcspace  = 1;
                    app.explorerobj.calcsettings.calcmethod = 1;
        
                case 'Native Space'
                    app.CalculationMethodButtonGroup.SelectedObject = ...
                        app.FiberBasedMethodButton;
        
                    app.explorerobj.calcsettings.calcspace  = 0;
                    app.explorerobj.calcsettings.calcmethod = 2;
            end
        end

        % Selection changed function: CalculationMethodButtonGroup
        function CalculationMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.CalculationMethodButtonGroup.SelectedObject;

            switch selectedButton.Text
        
                case 'E-field Based Method'
                    app.CalculateinDropDown.Value = 'Template Space';
                    app.CalculateinDropDown.Enable = 'on';
        
                    app.explorerobj.calcsettings.calcspace  = 1;
                    app.explorerobj.calcsettings.calcmethod = 1;
        
                case 'Fiber Based Method'
                    app.CalculateinDropDown.Value = 'Native Space';
                    app.CalculateinDropDown.Enable = 'on';
        
                    app.explorerobj.calcsettings.calcspace  = 0;
                    app.explorerobj.calcsettings.calcmethod = 2;
            end
            
        end

        % Value changed function: NormalizeFingerprintsCheckBox
        function NormalizeFingerprintsCheckBoxValueChanged(app, event)
            value = app.NormalizeFingerprintsCheckBox.Value;
            app.explorerobj.calcsettings.normalize_fp = value;
        end

        % Value changed function: SmoothFingerprintsCheckBox
        function SmoothFingerprintsCheckBoxValueChanged(app, event)
            value = app.SmoothFingerprintsCheckBox.Value;
            app.explorerobj.calcsettings.smooth_fp = value;
        end

        % Value changed function: SSResolutionEditField
        function SSResolutionEditFieldValueChanged(app, event)
            value = app.SSResolutionEditField.Value;
            app.explorerobj.calcsettings.sweetspotresolution = value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculationSettingsPanel = uipanel(app.UIFigure);
            app.CalculationSettingsPanel.Title = 'Calculation Settings';
            app.SaveButton = uibutton(app.CalculationSettingsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NetworkmappingButtonGroup = uibuttongroup(app.CalculationSettingsPanel);
            app.NetworkmappingButtonGroup.Title = 'Networkmapping';
            app.ResolutionstructuralDropDownLabel = uilabel(app.NetworkmappingButtonGroup);
            app.ResolutionstructuralDropDownLabel.Text = 'Resolution structural';
            app.ResolutionstructuralDropDown = uidropdown(app.NetworkmappingButtonGroup);
            app.ResolutionstructuralDropDown.Items = {'2 mm', '1 mm', '0.5 mm'};
            app.ResolutionstructuralDropDown.Editable = 'on';
            app.ResolutionstructuralDropDown.ValueChangedFcn = createCallbackFcn(app, @ResolutionstructuralDropDownValueChanged, true);
            app.ResolutionstructuralDropDown.Value = '2 mm';
            app.SmoothFingerprintsCheckBox = uicheckbox(app.NetworkmappingButtonGroup);
            app.SmoothFingerprintsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothFingerprintsCheckBoxValueChanged, true);
            app.SmoothFingerprintsCheckBox.Text = 'Smooth Fingerprints';
            app.NormalizeFingerprintsCheckBox = uicheckbox(app.NetworkmappingButtonGroup);
            app.NormalizeFingerprintsCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeFingerprintsCheckBoxValueChanged, true);
            app.NormalizeFingerprintsCheckBox.Text = 'Normalize Fingerprints';
            app.GlobalSettingsButtonGroup = uibuttongroup(app.CalculationSettingsPanel);
            app.GlobalSettingsButtonGroup.Title = 'Global Settings';
            app.ThresholdEditField_3Label = uilabel(app.GlobalSettingsButtonGroup);
            app.ThresholdEditField_3Label.Text = 'NaN all values below';
            app.ThresholdEditField = uieditfield(app.GlobalSettingsButtonGroup, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 100;
            app.VmLabel = uilabel(app.GlobalSettingsButtonGroup);
            app.VmLabel.Text = 'V/m';
            app.SweetspotButtonGroup = uibuttongroup(app.UIFigure);
            app.SweetspotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SweetspotButtonGroupSelectionChanged, true);
            app.SweetspotButtonGroup.Title = 'Sweetspot';
            app.SSResolutionEditFieldLabel = uilabel(app.SweetspotButtonGroup);
            app.SSResolutionEditFieldLabel.Text = 'Resolution';
            app.SSResolutionEditField = uieditfield(app.SweetspotButtonGroup, 'numeric');
            app.SSResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @SSResolutionEditFieldValueChanged, true);
            app.SSResolutionEditField.Value = 0.5;
            app.mmLabel = uilabel(app.SweetspotButtonGroup);
            app.mmLabel.Text = 'mm';
            app.FiberfilteringButtonGroup = uibuttongroup(app.UIFigure);
            app.FiberfilteringButtonGroup.Title = 'Fiberfiltering';
            app.CalculationMethodButtonGroup = uibuttongroup(app.FiberfilteringButtonGroup);
            app.CalculationMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalculationMethodButtonGroupSelectionChanged, true);
            app.CalculationMethodButtonGroup.Title = 'Calculation Method';
            app.EFieldBasedMethodButton = uiradiobutton(app.CalculationMethodButtonGroup);
            app.EFieldBasedMethodButton.Text = 'E-Field Based Method';
            app.EFieldBasedMethodButton.Value = true;
            app.FiberBasedMethodButton = uiradiobutton(app.CalculationMethodButtonGroup);
            app.FiberBasedMethodButton.Text = 'Fiber Based Method';
            app.CalculateinDropDownLabel = uilabel(app.FiberfilteringButtonGroup);
            app.CalculateinDropDownLabel.Text = 'Calculate in...';
            app.CalculateinDropDown = uidropdown(app.FiberfilteringButtonGroup);
            app.CalculateinDropDown.Items = {'Template Space', 'Native Space'};
            app.CalculateinDropDown.ValueChangedFcn = createCallbackFcn(app, @CalculateinDropDownValueChanged, true);
            app.CalculateinDropDown.Value = 'Template Space';
            app.BiophysicalModelFamilyButtonGroup = uibuttongroup(app.FiberfilteringButtonGroup);
            app.BiophysicalModelFamilyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BiophysicalModelFamilyButtonGroupSelectionChanged, true);
            app.BiophysicalModelFamilyButtonGroup.Title = 'Biophysical Model Family';
            app.StimulationVolumesButton = uiradiobutton(app.BiophysicalModelFamilyButtonGroup);
            app.StimulationVolumesButton.Text = 'Stimulation Volumes';
            app.StimulationVolumesButton.Value = true;
            app.PathwayActionModelingButton = uiradiobutton(app.BiophysicalModelFamilyButtonGroup);
            app.PathwayActionModelingButton.Text = 'Pathway Action Modeling';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_calculate_unifiedmapping(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end