classdef ea_delete_pts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClearRemoveSelectedPatientsUIFigure  matlab.ui.Figure
        RemovePatients    matlab.ui.control.CheckBox
        CancelButton      matlab.ui.control.Button
        YesButton         matlab.ui.control.Button
        ClearSourcedata   matlab.ui.control.CheckBox
        ClearRawdata      matlab.ui.control.CheckBox
        ClearDerivatives  matlab.ui.control.CheckBox
        StatusImage       matlab.ui.control.Image
        PromptLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        derivatives
        rawdata
        sourcedata
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, BIDSRoot, subjId)
            if ~iscell(subjId)
                subjId = {subjId};
            end
            app.derivatives = fullfile(BIDSRoot, 'derivatives', 'leaddbs', strcat('sub-', subjId));
            app.rawdata = fullfile(BIDSRoot, 'rawdata', strcat('sub-', subjId));
            app.sourcedata = fullfile(BIDSRoot, 'sourcedata', strcat('sub-', subjId));
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            ea_busyaction('on', app.StatusImage);

            if app.ClearDerivatives.Value
                ea_delete(fullfile(app.derivatives, '*'));
            end

            if app.ClearRawdata.Value
                ea_delete(fullfile(app.rawdata, '*'));
            end

            if app.ClearSourcedata.Value
                ea_delete(fullfile(app.sourcedata, '*'));
            end

            if app.RemovePatients.Value
                ea_delete(app.derivatives);
                ea_delete(app.rawdata);
                ea_delete(app.sourcedata);
            end

            ea_busyaction('off', app.StatusImage);

            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Close request function: ClearRemoveSelectedPatientsUIFigure
        function ClearRemoveSelectedPatientsUIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClearRemoveSelectedPatientsUIFigure = uifigure('Visible', 'off');
            app.ClearRemoveSelectedPatientsUIFigure.Name = 'Clear/Remove Selected Patients';
            app.ClearRemoveSelectedPatientsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClearRemoveSelectedPatientsUIFigureCloseRequest, true);
            app.PromptLabel = uilabel(app.ClearRemoveSelectedPatientsUIFigure);
            app.PromptLabel.Text = 'Are you sure you want to clear/remove the selected patients?';
            app.StatusImage = uiimage(app.ClearRemoveSelectedPatientsUIFigure);
            app.StatusImage.Tag = 'busystatus';
            app.StatusImage.Visible = 'off';
            app.ClearDerivatives = uicheckbox(app.ClearRemoveSelectedPatientsUIFigure);
            app.ClearDerivatives.Text = 'Clear ''derivatives''';
            app.ClearRawdata = uicheckbox(app.ClearRemoveSelectedPatientsUIFigure);
            app.ClearRawdata.Text = 'Clear ''rawdata''';
            app.ClearSourcedata = uicheckbox(app.ClearRemoveSelectedPatientsUIFigure);
            app.ClearSourcedata.Text = 'Clear ''sourcedata''';
            app.YesButton = uibutton(app.ClearRemoveSelectedPatientsUIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.CancelButton = uibutton(app.ClearRemoveSelectedPatientsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.RemovePatients = uicheckbox(app.ClearRemoveSelectedPatientsUIFigure);
            app.RemovePatients.Text = 'Remove selected patients from the dataset';
            app.RemovePatients.Value = true;
            % Show the figure after all components are created
            app.ClearRemoveSelectedPatientsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_delete_pts(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ClearRemoveSelectedPatientsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ClearRemoveSelectedPatientsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClearRemoveSelectedPatientsUIFigure)
        end
    end
end